classdef appInterface1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Site                           matlab.ui.container.Tab
        GEOGRAPHICALDATALabel          matlab.ui.control.Label
        LatitudeDegreeLabel            matlab.ui.control.Label
        LatitudeI                      matlab.ui.control.NumericEditField
        LongitudeDegreeLabel           matlab.ui.control.Label
        LongitudeI                     matlab.ui.control.NumericEditField
        AltitudemeterLabel             matlab.ui.control.Label
        AltitudeI                      matlab.ui.control.NumericEditField
        StandardLongitudeDegreeLabel   matlab.ui.control.Label
        StandardLongitudeI             matlab.ui.control.Spinner
        LatitudeLabel                  matlab.ui.control.Label
        StLongitudeLabel               matlab.ui.control.Label
        LongitudeLabel                 matlab.ui.control.Label
        AltitudeLabel                  matlab.ui.control.Label
        HELPSwitchLabel_2              matlab.ui.control.Label
        HELPSite                       matlab.ui.control.Switch
        Meteo                          matlab.ui.container.Tab
        MeteorologicalinputdataListBoxLabel  matlab.ui.control.Label
        InputDataI                     matlab.ui.control.ListBox
        TimeSeriesDropDownLabel        matlab.ui.control.Label
        TimeSeriesI                    matlab.ui.control.DropDown
        METEOROLOGICALINPUTDATALabel   matlab.ui.control.Label
        MetDataI                       matlab.ui.control.Table
        Gdm0Label1                     matlab.ui.control.Label
        Gdm0Label2                     matlab.ui.control.Label
        TmmLabel                       matlab.ui.control.Label
        TMmLabel                       matlab.ui.control.Label
        HELPSwitchLabel_3              matlab.ui.control.Label
        HELPMeteo                      matlab.ui.control.Switch
        PVgenerator                    matlab.ui.container.Tab
        PVnomkWpEditFieldLabel         matlab.ui.control.Label
        PVnomI                         matlab.ui.control.NumericEditField
        CVPTC1Label                    matlab.ui.control.Label
        CVPTI                          matlab.ui.control.NumericEditField
        NOCTCLabel                     matlab.ui.control.Label
        NOCTI                          matlab.ui.control.NumericEditField
        NOCTLabel                      matlab.ui.control.Label
        ThermalresistanceCm2WLabel     matlab.ui.control.Label
        RthI                           matlab.ui.control.NumericEditField
        PVnomLabel                     matlab.ui.control.Label
        CVPTLabel                      matlab.ui.control.Label
        RthLabel1                      matlab.ui.control.Label
        RthLabel2                      matlab.ui.control.Label
        PVGENERATOR12Label             matlab.ui.control.Label
        HELPSwitchLabel_4              matlab.ui.control.Label
        HELPPVgen                      matlab.ui.control.Switch
        PVgenenerator2                 matlab.ui.container.Tab
        InclinationDegreeLabel         matlab.ui.control.Label
        inclinationGround              matlab.ui.control.NumericEditField
        InclinationStaticLabel         matlab.ui.control.Label
        OrientationDegreeLabel         matlab.ui.control.Label
        orientationI                   matlab.ui.control.NumericEditField
        OrientationLabel1              matlab.ui.control.Label
        OrientationLabel2              matlab.ui.control.Label
        PVGENERATOR22Label             matlab.ui.control.Label
        MOUNTINGSTRUCTURELabel         matlab.ui.control.Label
        MOUNTING                       matlab.ui.container.ButtonGroup
        Staticground                   matlab.ui.control.RadioButton
        Staticdelta                    matlab.ui.control.RadioButton
        TrackerHorizontal              matlab.ui.control.RadioButton
        TrackerVertical                matlab.ui.control.RadioButton
        TrackerTwo                     matlab.ui.control.RadioButton
        StaticgroundorroofLabel        matlab.ui.control.Label
        StaticdeltaLabel               matlab.ui.control.Label
        AzimutaltrackingLabel          matlab.ui.control.Label
        InclinationDegreeLabel_2       matlab.ui.control.Label
        inclinationI_delta             matlab.ui.control.NumericEditField
        InclinationDegreeLabel_3       matlab.ui.control.Label
        inclinationI_tracking          matlab.ui.control.NumericEditField
        InclinationDeltaLabel1         matlab.ui.control.Label
        InclinationDeltaLabel2         matlab.ui.control.Label
        InclinationTrackingLabel       matlab.ui.control.Label
        HELPSwitchLabel_12             matlab.ui.control.Label
        HELPPVgen_2                    matlab.ui.control.Switch
        Inverter                       matlab.ui.container.Tab
        PInomkWEditFieldLabel          matlab.ui.control.Label
        PInomI                         matlab.ui.control.NumericEditField
        PImaxkWEditFieldLabel          matlab.ui.control.Label
        PImaxI                         matlab.ui.control.NumericEditField
        NominaloutputLabel             matlab.ui.control.Label
        MaximumoutputLabel             matlab.ui.control.Label
        InverterCurveI                 matlab.ui.container.ButtonGroup
        ModelparametersButton          matlab.ui.control.ToggleButton
        PowerefficiencycurveButton     matlab.ui.control.ToggleButton
        k0EditFieldLabel               matlab.ui.control.Label
        k0I                            matlab.ui.control.NumericEditField
        k1EditFieldLabel               matlab.ui.control.Label
        k1I                            matlab.ui.control.NumericEditField
        k2EditFieldLabel               matlab.ui.control.Label
        k2I                            matlab.ui.control.NumericEditField
        pointsTable                    matlab.ui.control.Table
        PowerEfgraphic                 matlab.ui.control.UIAxes
        INVERTERLabel                  matlab.ui.control.Label
        efficiencyLabel1               matlab.ui.control.Label
        efficiencyLabel2               matlab.ui.control.Label
        efficiencyLabel3               matlab.ui.control.Label
        efficiencyLabel4               matlab.ui.control.Label
        k0Label                        matlab.ui.control.Label
        k1Label                        matlab.ui.control.Label
        k2Label                        matlab.ui.control.Label
        pacLabel                       matlab.ui.control.Label
        EfficiencyLabel                matlab.ui.control.Label
        HELPSwitchLabel_8              matlab.ui.control.Label
        HELPInverter                   matlab.ui.control.Switch
        Wiring                         matlab.ui.container.Tab
        WDCLabel                       matlab.ui.control.Label
        WDCI                           matlab.ui.control.NumericEditField
        WACLabel                       matlab.ui.control.Label
        WACI                           matlab.ui.control.NumericEditField
        DCLabel                        matlab.ui.control.Label
        ACLabel                        matlab.ui.control.Label
        WIRINGLabel                    matlab.ui.control.Label
        Bat                            matlab.ui.container.Tab
        CBATkWhLabel                   matlab.ui.control.Label
        CBATI                          matlab.ui.control.NumericEditField
        SOCmaxEditFieldLabel           matlab.ui.control.Label
        SOCmaxI                        matlab.ui.control.NumericEditField
        SOCminEditFieldLabel           matlab.ui.control.Label
        SOCminI                        matlab.ui.control.NumericEditField
        SOCmaxLabel                    matlab.ui.control.Label
        SOCminLabel                    matlab.ui.control.Label
        CBATLabel                      matlab.ui.control.Label
        BATTERYStandalonePVsystemsLabel  matlab.ui.control.Label
        HELPSwitchLabel_5              matlab.ui.control.Label
        HELPBattery                    matlab.ui.control.ToggleSwitch
        Load                           matlab.ui.container.Tab
        LdmGraphic                     matlab.ui.control.UIAxes
        LdmI                           matlab.ui.control.Table
        TotalEditFieldLabel            matlab.ui.control.Label
        TotalLdmI                      matlab.ui.control.NumericEditField
        LdmLabel                       matlab.ui.control.Label
        FhI                            matlab.ui.control.Table
        FhGraphic                      matlab.ui.control.UIAxes
        FhLabel1                       matlab.ui.control.Label
        FhLabel2                       matlab.ui.control.Label
        FhLabel3                       matlab.ui.control.Label
        FhLabel4                       matlab.ui.control.Label
        TotalEditFieldLabel_2          matlab.ui.control.Label
        TotalFh                        matlab.ui.control.NumericEditField
        HELPSwitchLabel_6              matlab.ui.control.Label
        HELPLoad                       matlab.ui.control.Switch
        LOADPROFILESLabel              matlab.ui.control.Label
        GenSet                         matlab.ui.container.Tab
        PGENnomkWLabel                 matlab.ui.control.Label
        PGENnomI                       matlab.ui.control.NumericEditField
        SOCstartEditFieldLabel         matlab.ui.control.Label
        SOCstartI                      matlab.ui.control.NumericEditField
        SOCstopEditFieldLabel          matlab.ui.control.Label
        SOCstopI                       matlab.ui.control.NumericEditField
        b0iEditFieldLabel              matlab.ui.control.Label
        b0iI                           matlab.ui.control.NumericEditField
        b1iEditFieldLabel              matlab.ui.control.Label
        b1iI                           matlab.ui.control.NumericEditField
        b0sEditFieldLabel              matlab.ui.control.Label
        b0sI                           matlab.ui.control.NumericEditField
        b1sEditFieldLabel              matlab.ui.control.Label
        b1sI                           matlab.ui.control.NumericEditField
        b0iUnitsLabel                  matlab.ui.control.Label
        b1iUnitsLabel                  matlab.ui.control.Label
        b0sUnitsLabel                  matlab.ui.control.Label
        b1sUnitsLabel                  matlab.ui.control.Label
        PGENnomLabel                   matlab.ui.control.Label
        SOCstartLabel                  matlab.ui.control.Label
        SOCstopLabel                   matlab.ui.control.Label
        FuelCons                       matlab.ui.control.Table
        FuelConsGraph                  matlab.ui.control.UIAxes
        b0iLabel                       matlab.ui.control.Label
        b1iLabel                       matlab.ui.control.Label
        b0sLabel                       matlab.ui.control.Label
        b1sLabel                       matlab.ui.control.Label
        HELPSwitchLabel                matlab.ui.control.Label
        HELPGenSet                     matlab.ui.control.Switch
        GENERATORSET                   matlab.ui.control.Label
        Wind                           matlab.ui.container.Tab
        WIND                           matlab.ui.control.Label
        PWnomkWEditFieldLabel          matlab.ui.control.Label
        PWnomI                         matlab.ui.control.NumericEditField
        VnommsEditFieldLabel           matlab.ui.control.Label
        VnomI                          matlab.ui.control.NumericEditField
        VcimsEditFieldLabel            matlab.ui.control.Label
        VciI                           matlab.ui.control.NumericEditField
        VcomsEditFieldLabel            matlab.ui.control.Label
        VcoI                           matlab.ui.control.NumericEditField
        CpeqkWms3EditFieldLabel        matlab.ui.control.Label
        CpeqI                          matlab.ui.control.NumericEditField
        PWnomLabel                     matlab.ui.control.Label
        VnomLabel                      matlab.ui.control.Label
        VciLabel                       matlab.ui.control.Label
        VcoLabel                       matlab.ui.control.Label
        CpeqLabel                      matlab.ui.control.Label
        HELPSwitchLabel_7              matlab.ui.control.Label
        HELPWind                       matlab.ui.control.ToggleSwitch
        PowerCurve                     matlab.ui.control.Table
        PowerCurveGraph                matlab.ui.control.UIAxes
        Pumping1                       matlab.ui.container.Tab
        PUMPLabel                      matlab.ui.control.Label
        Qratedm3hEditFieldLabel        matlab.ui.control.Label
        QratedI                        matlab.ui.control.NumericEditField
        HratedmEditFieldLabel          matlab.ui.control.Label
        HratedI                        matlab.ui.control.NumericEditField
        LiquidDensitykgm3EditFieldLabel  matlab.ui.control.Label
        LiquidDensityI                 matlab.ui.control.NumericEditField
        QratedLabel                    matlab.ui.control.Label
        HratedLabel                    matlab.ui.control.Label
        densityLabel                   matlab.ui.control.Label
        WELLBOREHOLELabel              matlab.ui.control.Label
        HsmLabel                       matlab.ui.control.Label
        HsI                            matlab.ui.control.NumericEditField
        Qtestm3hLabel                  matlab.ui.control.Label
        QtestI                         matlab.ui.control.NumericEditField
        HdrmLabel                      matlab.ui.control.Label
        HdrI                           matlab.ui.control.NumericEditField
        kw1mm3hLabel                   matlab.ui.control.Label
        kw1I                           matlab.ui.control.NumericEditField
        kw2mm3h2Label                  matlab.ui.control.Label
        kw2I                           matlab.ui.control.NumericEditField
        HsLabel                        matlab.ui.control.Label
        QtestLabel                     matlab.ui.control.Label
        HdrLabel                       matlab.ui.control.Label
        kw1Label1                      matlab.ui.control.Label
        kw1Label2                      matlab.ui.control.Label
        kw2Label                       matlab.ui.control.Label
        WATERTANKLabel                 matlab.ui.control.Label
        WTCm3Label                     matlab.ui.control.Label
        WTCI                           matlab.ui.control.NumericEditField
        HrmLabel                       matlab.ui.control.Label
        HrI                            matlab.ui.control.NumericEditField
        WTCLabel                       matlab.ui.control.Label
        WTCLabel2                      matlab.ui.control.Label
        HrLabel                        matlab.ui.control.Label
        PUMPING1                       matlab.ui.control.Label
        HELPSwitchLabel_13             matlab.ui.control.Label
        HELPPump1                      matlab.ui.control.ToggleSwitch
        Pumping2                       matlab.ui.container.Tab
        PUMPING2                       matlab.ui.control.Label
        ks0mLabel                      matlab.ui.control.Label
        ks0I                           matlab.ui.control.NumericEditField
        ks1mm3hLabel                   matlab.ui.control.Label
        ks1I                           matlab.ui.control.NumericEditField
        HfmLabel                       matlab.ui.control.Label
        HfI                            matlab.ui.control.NumericEditField
        ks0Label                       matlab.ui.control.Label
        ks1Label                       matlab.ui.control.Label
        HfLabel                        matlab.ui.control.Label
        SYSTEMCURVELabel               matlab.ui.control.Label
        systemCurveTable               matlab.ui.control.Table
        systemCurveGraph               matlab.ui.control.UIAxes
        HELPSwitchLabel_9              matlab.ui.control.Label
        HELPPump2                      matlab.ui.control.ToggleSwitch
        ks2mm3h2Label                  matlab.ui.control.Label
        ks2I                           matlab.ui.control.NumericEditField
        ks2Label                       matlab.ui.control.Label
        Pumping3                       matlab.ui.container.Tab
        pumpCurveTable                 matlab.ui.control.Table
        pumpCurveGraph                 matlab.ui.control.UIAxes
        powerCurvesTable               matlab.ui.control.Table
        powerCurvesGraph               matlab.ui.control.UIAxes
        P1Label1                       matlab.ui.control.Label
        P1Label2                       matlab.ui.control.Label
        P2Label1                       matlab.ui.control.Label
        P2Label2                       matlab.ui.control.Label
        PUMPING3                       matlab.ui.control.Label
        PUMPCURVELabel                 matlab.ui.control.Label
        POWERCURVESLabel               matlab.ui.control.Label
        HELPSwitchLabel_10             matlab.ui.control.Label
        HELPPump3                      matlab.ui.control.ToggleSwitch
        Pumping4                       matlab.ui.container.Tab
        PUMPING4                       matlab.ui.control.Label
        MOTORLabel                     matlab.ui.control.Label
        P2ratedkWEditFieldLabel        matlab.ui.control.Label
        P2ratedI                       matlab.ui.control.NumericEditField
        RPMnomrpmLabel                 matlab.ui.control.Label
        RPMnomI                        matlab.ui.control.NumericEditField
        RPMcool0100Label               matlab.ui.control.Label
        RPMcoolI                       matlab.ui.control.NumericEditField
        RPMmax100150Label              matlab.ui.control.Label
        RPMmaxI                        matlab.ui.control.NumericEditField
        P2RatedLabel                   matlab.ui.control.Label
        RPMnomLabel                    matlab.ui.control.Label
        RPMcoolLabel                   matlab.ui.control.Label
        RPMmaxLabel                    matlab.ui.control.Label
        PowerEfficTable                matlab.ui.control.Table
        PowerEfficGraph                matlab.ui.control.UIAxes
        HELPSwitchLabel_11             matlab.ui.control.Label
        HELPPump4                      matlab.ui.control.ToggleSwitch
        Options                        matlab.ui.container.Tab
        PVApplicationLabel             matlab.ui.control.Label
        ApplicationI                   matlab.ui.control.ListBox
        DiffuseskymodelDropDownLabel   matlab.ui.control.Label
        DiffuseModel                   matlab.ui.control.DropDown
        DustLabel                      matlab.ui.control.Label
        DustDegreeI                    matlab.ui.container.ButtonGroup
        Clean                          matlab.ui.control.ToggleButton
        Low                            matlab.ui.control.ToggleButton
        Medium                         matlab.ui.control.ToggleButton
        High                           matlab.ui.control.ToggleButton
        GroundreflectanceSpinnerLabel  matlab.ui.control.Label
        GroundReflectanceI             matlab.ui.control.Spinner
        DiffuseFractionI               matlab.ui.container.ButtonGroup
        Page                           matlab.ui.control.RadioButton
        CollaresPereira                matlab.ui.control.RadioButton
        Erbs                           matlab.ui.control.RadioButton
        Macagnan                       matlab.ui.control.RadioButton
        SimulationstepEditFieldLabel   matlab.ui.control.Label
        SimulationstepI                matlab.ui.control.NumericEditField
        to3600secondsLabel             matlab.ui.control.Label
        SIMULATIONOPTIONSLabel         matlab.ui.control.Label
        calculate                      matlab.ui.control.Button
        SaveData                       matlab.ui.control.CheckBox
        instructions                   matlab.ui.control.Label
        ProjectnameLabel               matlab.ui.control.Label
        nameI                          matlab.ui.control.EditField
        loadProject                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        %coefficients for the calculation of F(l/h) depending on b0i, b1i, PGENnom
        coef1; 
        coef2; 
        
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Open a window to select the input data .mat file (Workspace in
            %Matlab)
            file1=uigetfile('*.mat','Select the input data file');
            % If the file is not selected, or the window is closed, the program stops.
            if isequal(file1,0)
                error('The file has not been selected');
            end
            
            %Load the workspace data
            load(file1);
            
            %Take each value from the workspace and assign it to each component
            app.PInomI.Value=PInom;
            app.PImaxI.Value=PImax;
            app.k0I.Value=k0;
            app.k1I.Value=k1;
            app.k2I.Value=k2;
            app.pointsTable.ColumnFormat={'numeric','numeric'};
            app.pointsTable.Data=points;
            app.GroundReflectanceI.Value=GroundReflectance;
            app.SimulationstepI.Value=SimulationStep;
            app.LatitudeI.Value=Latitude;
            app.LongitudeI.Value=Longitude;   
            app.AltitudeI.Value=Altitude;      
            app.StandardLongitudeI.Value=StandardLongitude;
            app.MetDataI.ColumnFormat={'numeric','numeric','numeric'};
            app.MetDataI.Data=months_Data;
            app.PVnomI.Value=PVnom;
            app.CVPTI.Value=CVPT;
            app.NOCTI.Value=NOCT;
            app.inclinationGround.Value=InclinationGround; 
            app.orientationI.Value=Orientation; 
            app.WACI.Value=WAC;
            app.WDCI.Value=WDC;
            app.CBATI.Value=CBAT;
            app.SOCmaxI.Value=SOCmax;
            app.SOCminI.Value=SOCmin;
            app.LdmI.ColumnFormat={'numeric'};
            app.LdmI.Data=Ldm_Data;
            app.TotalLdmI.Value=Edemanda;
            app.FhI.ColumnFormat={'numeric'};
            app.FhI.Data=F_Data;
            app.PGENnomI.Value=PGENnom;
            app.SOCstartI.Value=SOCstart;
            app.SOCstopI.Value=SOCstop;
            app.b0iI.Value=b0i;
            app.b1iI.Value=b1i;
            app.b0sI.Value=b0s;
            app.b1sI.Value=b1s;
            app.PWnomI.Value=PWnom;
            app.VnomI.Value=Vnom;
            app.VciI.Value=Vci;
            app.VcoI.Value=Vco;
            app.QratedI.Value=Qrated;
            app.HratedI.Value=Hrated;
            app.LiquidDensityI.Value=Density;
            app.HfI.Value=Hf;
            app.pumpCurveTable.ColumnFormat={'numeric','numeric'};
            app.pumpCurveTable.Data=PumpCurve;
            app.powerCurvesTable.ColumnFormat={'numeric','numeric','numeric'};
            app.powerCurvesTable.Data=PowerCurves;
            app.P2ratedI.Value=P2rated;
            app.RPMnomI.Value=RPMnom;
            app.RPMcoolI.Value=RPMcoolM;
            app.RPMmaxI.Value=RPMmaxM;
            app.PowerEfficTable.ColumnFormat={'numeric','numeric'};
            app.PowerEfficTable.Data=PowerEffic;
            app.nameI.Value=name;
            app.inclinationI_delta.Value=InclinationDelta;
            app.inclinationI_tracking.Value=InclinationTracking;
            app.HsI.Value=Hs;
            app.QtestI.Value=Qtest;
            app.HdrI.Value=Hdr;
            app.kw1I.Value=kw1;
            app.kw2I.Value=kw2;
            app.WTCI.Value=WTC;
            app.HrI.Value=Hr;
            
            if(InverterCurve==1)
                app.ModelparametersButton.Value=true;
                app.PowerefficiencycurveButton.Value=false;
                assignin("base","InverterCurve",1);
            else
                app.PowerefficiencycurveButton.Value=true;
                app.ModelparametersButton.Value=false;
                assignin("base","InverterCurve",2);
            end
            
            switch Application
                case 1
                    app.ApplicationI.Value='Grid-Connected';
                    assignin("base","Application",1);
                case 2
                    app.ApplicationI.Value='Stand-alone PV system';
                    assignin("base","Application",2);
                case 3
                    app.ApplicationI.Value='Hybrid PV-diesel';
                    assignin("base","Application",3);
                case 4
                    app.ApplicationI.Value='Hybrid PV-wind-diesel';
                    assignin("base","Application",4);
                case 5
                   app.ApplicationI.Value='Water pumping';
                   assignin("base","Application",5);
            end
            
            if(DustDegree==1) 
               app.Clean.Value=true;
               app.Low.Value=false;
               app.Medium.Value=false;
               app.High.Value=false;
               assignin("base","DustDegree",1);
            elseif(DustDegree==2)
               app.Low.Value=true;
               app.Medium.Value=false;
               app.High.Value=false;
               app.Clean.Value=false;
               assignin("base","DustDegree",2);
            elseif(DustDegree==3)
               app.Medium.Value=true;
               app.High.Value=false;
               app.Clean.Value=false;
               app.Low.Value=false;
               assignin("base","DustDegree",3);
            elseif (DustDegree==4)
               app.High.Value=true;
               app.Clean.Value=false;
               app.Low.Value=false;
               app.Medium.Value=false;
               assignin("base","DustDegree",4);
            end

            switch Diffuse_model 
                case 1
                    app.DiffuseModel.Value='Isotropic';
                    assignin("base","Diffuse_model",1);
                case 2
                    app.DiffuseModel.Value='Anisotropic (Hay)';
                    assignin("base","Diffuse_model",2);
                case 3
                    app.DiffuseModel.Value='Anisotropic (Perez)';
                    assignin("base","Diffuse_model",3);
            end
            
            if(Diffuse_fraction==1)
                app.Page.Value=true;
                assignin("base","Diffuse_fraction",1);
            elseif(Diffuse_fraction==2)
                app.CollaresPereira.Value=true;
                assignin("base","Diffuse_fraction",2);
            elseif(Diffuse_fraction==3)
                app.Erbs.Value=true;
                assignin("base","Diffuse_fraction",3);
            else
                app.Macagnan.Value=true;
                assignin("base","Diffuse_fraction",4);
            end
            
            if(Mounting==1)
                app.Staticground.Value=true;
                assignin("base","Mounting",1);
            elseif(Mounting==2)
                app.Staticdelta.Value=true;
                assignin("base","Mounting",2);
            elseif(Mounting==3)
                app.TrackerHorizontal.Value=true;
                assignin("base","Mounting",3);
            elseif(Mounting==4)
                app.TrackerVertical.Value=true;
                assignin("base","Mounting",4);
            else
                app.TrackerTwo.Value=true;
                assignin("base","Mounting",5);
            end
            
            
            switch InputData 
               case 1
                   app.InputDataI.Value='Monthly averages from the table';
                   assignin("base","InputData",1);
               case 2
                   app.InputDataI.Value='Monthly averages obtained from PVGIS TMY';
                   assignin("base","InputData",2);
               case 3
                   app.InputDataI.Value='Hourly values from PVGIS TMY';
                   assignin("base","InputData",3);
               case 4
                   app.InputDataI.Value='Monthly averages obtained from USA TMY3';
                   assignin("base","InputData",4);
               case 5
                   app.InputDataI.Value='Hourly values from USA TMY3';
                   assignin("base","InputData",5);
             end
             
             switch TimeSeries 
                case 1
                    app.TimeSeriesI.Value='Mean Days';
                    assignin("base","TimeSeries",1);
                case 2
                    app.TimeSeriesI.Value='Aguiar';
                    assignin("base","TimeSeries",2);
             end
             
             app.RthI.Value=(app.NOCTI.Value-20)/800;
             
             app.ks0I.Value = app.HrI.Value + app.HsI.Value;
             
             if(app.HfI.Value==0 || app.QratedI.Value==0)
                 app.ks2I.Value=app.kw2I.Value;
             else
                 app.ks2I.Value=(app.HfI.Value/(app.QratedI.Value^2))+app.kw2I.Value;
             end
             
      
            if(app.PWnomI.Value==0 || app.VnomI.Value==0 || app.VciI.Value==0)
                app.CpeqI.Value = 0;
            else
                app.CpeqI.Value = app.PWnomI.Value/(app.VnomI.Value^3-app.VciI.Value^3);
            end
          
            app.coef1=(app.b0iI.Value+app.b1iI.Value*app.PGENnomI.Value);
            app.coef2=(app.b0sI.Value+app.b1sI.Value*app.PGENnomI.Value);
            app.FuelCons.Data=[0 app.coef1+app.coef2*0; ...
                0.25*app.PGENnomI.Value app.coef1+app.coef2*0.25*app.PGENnomI.Value; ...
                0.5*app.PGENnomI.Value app.coef1+app.coef2*0.5*app.PGENnomI.Value; ...
                0.75*app.PGENnomI.Value app.coef1+app.coef2*0.75*app.PGENnomI.Value; ...
                app.PGENnomI.Value app.coef1+app.coef2*app.PGENnomI.Value];
            
            app.PowerCurve.Data=[0 0; app.VciI.Value 0;...
                app.VciI.Value+0.2*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.2*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VciI.Value+0.4*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.4*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VciI.Value+0.6*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.6*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VciI.Value+0.8*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.8*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VnomI.Value app.PWnomI.Value; app.VcoI.Value app.PWnomI.Value; app.VcoI.Value 0];
            
             
            app.TotalFh.Value=sum(app.FhI.Data(1:24,1));
             
            app.systemCurveTable.Data=[0 app.ks0I.Value;...
                0.15*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.15*app.QratedI.Value+app.ks2I.Value*(0.15*app.QratedI.Value)^2;...
                0.3*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.3*app.QratedI.Value+app.ks2I.Value*(0.3*app.QratedI.Value)^2;...
                0.45*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.45*app.QratedI.Value+app.ks2I.Value*(0.45*app.QratedI.Value)^2;...
                0.6*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.6*app.QratedI.Value+app.ks2I.Value*(0.6*app.QratedI.Value)^2;...
                0.75*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.75*app.QratedI.Value+app.ks2I.Value*(0.75*app.QratedI.Value)^2;...
                0.9*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.9*app.QratedI.Value+app.ks2I.Value*(0.9*app.QratedI.Value)^2;...
                1.05*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.05*app.QratedI.Value+app.ks2I.Value*(1.05*app.QratedI.Value)^2;...
                1.2*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.2*app.QratedI.Value+app.ks2I.Value*(1.2*app.QratedI.Value)^2;...
                1.35*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.35*app.QratedI.Value+app.ks2I.Value*(1.35*app.QratedI.Value)^2];
              
            
            %Initial graphics
            plot(app.LdmGraphic,app.LdmI.Data(1:12,1));
            plot(app.FhGraphic,app.FhI.Data(1:24,1));
            plot(app.PowerEfgraphic,app.pointsTable.Data(1:6,1),app.pointsTable.Data(1:6,2));
            plot(app.FuelConsGraph,app.FuelCons.Data(1:5,1),app.FuelCons.Data(1:5,2));
            plot(app.PowerCurveGraph,app.PowerCurve.Data(1:9,1),app.PowerCurve.Data(1:9,2));
            plot(app.systemCurveGraph,app.systemCurveTable.Data(1:10,1),app.systemCurveTable.Data(1:10,2));
            plot(app.pumpCurveGraph,app.pumpCurveTable.Data(1:7,1),app.pumpCurveTable.Data(1:7,2));
            plot(app.powerCurvesGraph,app.powerCurvesTable.Data(1:7,1),app.powerCurvesTable.Data(1:7,2), "m");
            plot(app.powerCurvesGraph,app.powerCurvesTable.Data(1:7,1),app.powerCurvesTable.Data(1:7,3), "b");
            plot(app.PowerEfficGraph,app.PowerEfficTable.Data(1:7,1),app.PowerEfficTable.Data(1:7,2));
           
            %Assign values to the workspace
            assignin("base","PInom",app.PInomI.Value);
            assignin("base","PImax",app.PImaxI.Value);
            assignin("base","k0",app.k0I.Value);
            assignin("base","k1",app.k1I.Value);
            assignin("base","k2",app.k2I.Value);
            assignin("base","points",app.pointsTable.Data);
            assignin("base","GroundReflectance",app.GroundReflectanceI.Value);
            assignin("base","SimulationStep",app.SimulationstepI.Value);
            assignin("base","Latitude",app.LatitudeI.Value);
            assignin("base","Longitude",app.LongitudeI.Value);   
            assignin("base","Altitude",app.AltitudeI.Value);      
            assignin("base","StandardLongitude",app.StandardLongitudeI.Value);
            assignin("base","months_Data",app.MetDataI.Data);
            assignin("base","PVnom",app.PVnomI.Value);
            assignin("base","CVPT",app.CVPTI.Value);
            assignin("base","NOCT",app.NOCTI.Value);
            assignin("base","InclinationGround",app.inclinationGround.Value); 
            assignin("base","Orientation",app.orientationI.Value); 
            assignin("base","Rth",app.RthI.Value);
            assignin("base","WAC",app.WACI.Value);
            assignin("base","WDC",app.WDCI.Value);
            assignin("base","CBAT",app.CBATI.Value);
            assignin("base","SOCmax",app.SOCmaxI.Value);
            assignin("base","SOCmin",app.SOCminI.Value);
            assignin("base","Ldm_Data",app.LdmI.Data);
            assignin("base","Edemanda",app.TotalLdmI.Value);
            assignin("base","F_Data",app.FhI.Data);
            assignin("base","PGENnom",app.PGENnomI.Value);
            assignin("base","SOCstart",app.SOCstartI.Value);
            assignin("base","SOCstop",app.SOCstopI.Value);
            assignin("base","b0i",app.b0iI.Value);
            assignin("base","b1i",app.b1iI.Value);
            assignin("base","b0s",app.b0sI.Value);
            assignin("base","b1s",app.b1sI.Value);
            assignin("base","PWnom",app.PWnomI.Value);
            assignin("base","Vnom",app.VnomI.Value);
            assignin("base","Vci",app.VciI.Value);
            assignin("base","Vco",app.VcoI.Value);
            assignin("base","Qrated",app.QratedI.Value);
            assignin("base","Hrated",app.HratedI.Value);
            assignin("base","Density",app.LiquidDensityI.Value);
            assignin("base","Hf",app.HfI.Value);
            assignin("base","PumpCurve",app.pumpCurveTable.Data);
            assignin("base","PowerCurves",app.powerCurvesTable.Data);
            assignin("base","P2rated",app.P2ratedI.Value);
            assignin("base","RPMnom",app.RPMnomI.Value);
            assignin("base","RPMcoolM",app.RPMcoolI.Value);
            assignin("base","RPMmaxM",app.RPMmaxI.Value);
            assignin("base","PowerEffic",app.PowerEfficTable.Data);
            assignin("base","saveDataApp",0);
            assignin("base","name",app.nameI.Value);
            assignin("base","InclinationTracking",app.inclinationI_tracking.Value);
            assignin("base","InclinationDelta",app.inclinationI_delta.Value);
            assignin("base","Hs",app.HsI.Value);
            assignin("base","Qtest",app.QtestI.Value);
            assignin("base","Hdr",app.HdrI.Value);
            assignin("base","kw1",app.kw1I.Value);
            assignin("base","kw2",app.kw2I.Value);
            assignin("base","WTC",app.WTCI.Value);
            assignin("base","Hr",app.HrI.Value);
            
        end

        % Value changed function: PInomI
        function PInomIValueChanged(app, event)
            assignin("base","PInom",app.PInomI.Value);
            
        end

        % Value changed function: PImaxI
        function PImaxIValueChanged(app, event)
            assignin("base","PImax",app.PImaxI.Value);
            
        end

        % Selection changed function: InverterCurveI
        function InverterCurveISelectionChanged(app, event)
            if(app.ModelparametersButton.Value==true)
                assignin("base","InverterCurve",1);
            else
                assignin("base","InverterCurve",2);
            end
            
        end

        % Cell edit callback: pointsTable
        function pointsTableCellEdit(app, event)
            assignin("base","points",app.pointsTable.Data);
            plot(app.PowerEfgraphic,app.pointsTable.Data(1:6,1),app.pointsTable.Data(1:6,2));
        end

        % Button pushed function: calculate
        function calculateButtonPushed(app, event)
            pvlite;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab; 
            
        end

        % Value changed function: k0I
        function k0IValueChanged(app, event)
           assignin("base","k0",app.k0I.Value);
            
        end

        % Value changed function: k1I
        function k1IValueChanged(app, event)
             assignin("base","k1",app.k1I.Value);
            
        end

        % Value changed function: k2I
        function k2IValueChanged(app, event)
             assignin("base","k2",app.k2I.Value);
             
        end

        % Value changed function: ApplicationI
        function ApplicationIValueChanged(app, event)

            switch app.ApplicationI.Value
                case 'Grid-Connected'
                    assignin("base","Application",1);
                case 'Stand-alone PV system'
                    assignin("base","Application",2);
                case 'Hybrid PV-diesel'
                    assignin("base","Application",3);
                case 'Hybrid PV-wind-diesel'
                    assignin("base","Application",4);
                case 'Water pumping'
                   assignin("base","Application",5);
                
            end
 
        end

        % Selection changed function: DustDegreeI
        function DustDegreeISelectionChanged(app, event)
           if(app.Clean.Value==true)
               assignin("base","DustDegree",1);
           elseif(app.Low.Value==true)
                assignin("base","DustDegree",2);
           elseif(app.Medium.Value==true)
                assignin("base","DustDegree",3);
           elseif (app.High.Value==true)
                assignin("base","DustDegree",4);
           end
            
        end

        % Value changed function: DiffuseModel
        function DiffuseModelValueChanged(app, event)
            switch app.DiffuseModel.Value
                case 'Isotropic'
                    assignin("base","Diffuse_model",1);
                case 'Anisotropic (Hay)'
                    assignin("base","Diffuse_model",2);
                case 'Anisotropic (Perez)'
                    assignin("base","Diffuse_model",3);
            end
     
        end

        % Value changed function: GroundReflectanceI
        function GroundReflectanceIValueChanged(app, event)
             assignin("base","GroundReflectance",app.GroundReflectanceI.Value);
           
        end

        % Selection changed function: DiffuseFractionI
        function DiffuseFractionISelectionChanged(app, event)
            if(app.Page.Value==true)
               assignin("base","Diffuse_fraction",1);
           elseif(app.CollaresPereira.Value==true)
                assignin("base","Diffuse_fraction",2);
           elseif(app.Erbs.Value==true)
                assignin("base","Diffuse_fraction",3);
           else
                assignin("base","Diffuse_fraction",4);
           end
        end

        % Value changed function: SimulationstepI
        function SimulationstepIValueChanged(app, event)
            assignin("base","SimulationStep",app.SimulationstepI.Value);
            
        end

        % Value changed function: LatitudeI
        function LatitudeIValueChanged(app, event)
            assignin("base","Latitude",app.LatitudeI.Value);
        end

        % Value changed function: LongitudeI
        function LongitudeIValueChanged(app, event)
            assignin("base","Longitude",app.LongitudeI.Value);       
        end

        % Value changed function: AltitudeI
        function AltitudeIValueChanged(app, event)
            assignin("base","Altitude",app.AltitudeI.Value);      
        end

        % Value changed function: StandardLongitudeI
        function StandardLongitudeIValueChanged(app, event)
            assignin("base","StandardLongitude",app.StandardLongitudeI.Value);
        end

        % Value changed function: InputDataI
        function InputDataIValueChanged(app, event)
           switch app.InputDataI.Value
               case 'Monthly averages from the table'
                   assignin("base","InputData",1);
               case 'Monthly averages obtained from PVGIS TMY'
                   assignin("base","InputData",2);
               case 'Hourly values from PVGIS TMY'
                   assignin("base","InputData",3);
               case 'Monthly averages obtained from USA TMY3'
                   assignin("base","InputData",4);
               case 'Hourly values from USA TMY3'
                   assignin("base","InputData",5);
           end
        end

        % Callback function
        function MeteoDataICellEdit(app, event)
           assignin("base","months",app.MeteoDataI.Data);
            
        end

        % Callback function
        function MeteoDataICellEdit2(app, event)
            assignin("base","months",app.MeteoDataI.Data);
        end

        % Value changed function: TimeSeriesI
        function TimeSeriesIValueChanged(app, event)
            switch app.TimeSeriesI.Value
                case 'Mean Days'
                    assignin("base","TimeSeries",1);
                case 'Aguiar'
                    assignin("base","TimeSeries",2);
            end
           
        end

        % Value changed function: PVnomI
        function PVnomIValueChanged(app, event)
            assignin("base","PVnom",app.PVnomI.Value);
        end

        % Value changed function: CVPTI
        function CVPTIValueChanged(app, event)
            assignin("base","CVPT",app.CVPTI.Value);
        end

        % Value changed function: NOCTI
        function NOCTIValueChanged(app, event)
            assignin("base","NOCT",app.NOCTI.Value);
            app.RthI.Value=(app.NOCTI.Value-20)/800;
            assignin("base","Rth",app.RthI.Value);
        end

        % Value changed function: orientationI
        function orientationIValueChanged(app, event)
            assignin("base","Orientation",app.orientationI.Value); 
        end

        % Value changed function: RthI
        function RthIValueChanged(app, event)
            assignin("base","Rth",app.RthI.Value);
        end

        % Value changed function: WDCI
        function WDCIValueChanged(app, event)
            assignin("base","WDC", app.WDCI.Value);
        end

        % Value changed function: WACI
        function WACIValueChanged(app, event)
            assignin("base","WAC", app.WACI.Value);
        end

        % Value changed function: CBATI
        function CBATIValueChanged(app, event)
            assignin("base","CBAT",app.CBATI.Value);
        end

        % Value changed function: SOCmaxI
        function SOCmaxIValueChanged(app, event)
            assignin("base","SOCmax",app.SOCmaxI.Value);
        end

        % Value changed function: SOCminI
        function SOCminIValueChanged(app, event)
            assignin("base","SOCmin",app.SOCminI.Value);
        end

        % Callback function
        function LdmICellEdit(app, event)
            assignin("base","Ldm_Data",app.LdmI.Data);
        end

        % Cell edit callback: LdmI
        function LdmICellEdit2(app, event)
            assignin("base","Ldm_Data",app.LdmI.Data);
            app.TotalLdmI.Value=31*app.LdmI.Data(1,1)+28*app.LdmI.Data(2,1)+31*app.LdmI.Data(3,1)+...
                30*app.LdmI.Data(4,1)+31*app.LdmI.Data(5,1)+30*app.LdmI.Data(6,1)+31*app.LdmI.Data(7,1)+...
                31*app.LdmI.Data(8,1)+30*app.LdmI.Data(9,1)+31*app.LdmI.Data(10,1)+...
                30*app.LdmI.Data(11,1)+31*app.LdmI.Data(12,1);
            assignin("base","Edemanda",app.TotalLdmI.Value);
            
            plot(app.LdmGraphic,app.LdmI.Data(1:12,1));
        end

        % Cell edit callback: FhI
        function FhICellEdit(app, event)
            assignin("base","F_Data",app.FhI.Data);
            plot(app.FhGraphic,app.FhI.Data(1:24,1));
            app.TotalFh.Value=sum(app.FhI.Data(1:24,1));
            
        end

        % Cell edit callback: MetDataI
        function MetDataICellEdit(app, event)
            assignin("base","months_Data",app.MetDataI.Data);
        end

        % Value changed function: PGENnomI
        function PGENnomIValueChanged(app, event)
            assignin("base","PGENnom",app.PGENnomI.Value);
            app.coef1=(app.b0iI.Value+app.b1iI.Value*app.PGENnomI.Value);
            app.coef2=(app.b0sI.Value+app.b1sI.Value*app.PGENnomI.Value);
            app.FuelCons.Data=[0 app.coef1+app.coef2*0; ...
                0.25*app.PGENnomI.Value app.coef1+app.coef2*0.25*app.PGENnomI.Value; ...
                0.5*app.PGENnomI.Value app.coef1+app.coef2*0.5*app.PGENnomI.Value; ...
                0.75*app.PGENnomI.Value app.coef1+app.coef2*0.75*app.PGENnomI.Value; ...
                app.PGENnomI.Value app.coef1+app.coef2*app.PGENnomI.Value];
            plot(app.FuelConsGraph,app.FuelCons.Data(1:5,1),app.FuelCons.Data(1:5,2));
            
        end

        % Value changed function: SOCstartI
        function SOCstartIValueChanged(app, event)
            assignin("base","SOCstart",app.SOCstartI.Value);
        end

        % Value changed function: SOCstopI
        function SOCstopIValueChanged(app, event)
            assignin("base","SOCstop",app.SOCstopI.Value);
        end

        % Value changed function: b0iI
        function b0iIValueChanged(app, event)
            assignin("base","b0i",app.b0iI.Value);
            app.coef1=(app.b0iI.Value+app.b1iI.Value*app.PGENnomI.Value);
            app.FuelCons.Data=[0 app.coef1+app.coef2*0; ...
                0.25*app.PGENnomI.Value app.coef1+app.coef2*0.25*app.PGENnomI.Value; ...
                0.5*app.PGENnomI.Value app.coef1+app.coef2*0.5*app.PGENnomI.Value; ...
                0.75*app.PGENnomI.Value app.coef1+app.coef2*0.75*app.PGENnomI.Value; ...
                app.PGENnomI.Value app.coef1+app.coef2*app.PGENnomI.Value];
            plot(app.FuelConsGraph,app.FuelCons.Data(1:5,1),app.FuelCons.Data(1:5,2));
            
        end

        % Value changed function: b1iI
        function b1iIValueChanged(app, event)
            assignin("base","b1i",app.b1iI.Value);
            app.coef1=(app.b0iI.Value+app.b1iI.Value*app.PGENnomI.Value);
            app.FuelCons.Data=[0 app.coef1+app.coef2*0; ...
                0.25*app.PGENnomI.Value app.coef1+app.coef2*0.25*app.PGENnomI.Value; ...
                0.5*app.PGENnomI.Value app.coef1+app.coef2*0.5*app.PGENnomI.Value; ...
                0.75*app.PGENnomI.Value app.coef1+app.coef2*0.75*app.PGENnomI.Value; ...
                app.PGENnomI.Value app.coef1+app.coef2*app.PGENnomI.Value];
            plot(app.FuelConsGraph,app.FuelCons.Data(1:5,1),app.FuelCons.Data(1:5,2));
            
        end

        % Value changed function: b0sI
        function b0sIValueChanged(app, event)
            assignin("base","b0s",app.b0sI.Value);
            app.coef2=(app.b0sI.Value+app.b1sI.Value*app.PGENnomI.Value);
            app.FuelCons.Data=[0 app.coef1+app.coef2*0; ...
                0.25*app.PGENnomI.Value app.coef1+app.coef2*0.25*app.PGENnomI.Value; ...
                0.5*app.PGENnomI.Value app.coef1+app.coef2*0.5*app.PGENnomI.Value; ...
                0.75*app.PGENnomI.Value app.coef1+app.coef2*0.75*app.PGENnomI.Value; ...
                app.PGENnomI.Value app.coef1+app.coef2*app.PGENnomI.Value];
            plot(app.FuelConsGraph,app.FuelCons.Data(1:5,1),app.FuelCons.Data(1:5,2));
            
        end

        % Value changed function: b1sI
        function b1sIValueChanged(app, event)
            assignin("base","b1s",app.b1sI.Value);
            app.coef2=(app.b0sI.Value+app.b1sI.Value*app.PGENnomI.Value);
            app.FuelCons.Data=[0 app.coef1+app.coef2*0; ...
                0.25*app.PGENnomI.Value app.coef1+app.coef2*0.25*app.PGENnomI.Value; ...
                0.5*app.PGENnomI.Value app.coef1+app.coef2*0.5*app.PGENnomI.Value; ...
                0.75*app.PGENnomI.Value app.coef1+app.coef2*0.75*app.PGENnomI.Value; ...
                app.PGENnomI.Value app.coef1+app.coef2*app.PGENnomI.Value];
            plot(app.FuelConsGraph,app.FuelCons.Data(1:5,1),app.FuelCons.Data(1:5,2));
            
        end

        % Value changed function: HELPGenSet
        function HELPGenSetValueChanged(app, event)
            if (app.HELPGenSet.Value=="On")
                app.b0iLabel.Visible = 'on';
                app.b1iLabel.Visible = 'on';
                app.b0sLabel.Visible = 'on';
                app.b1sLabel.Visible = 'on';
                app.PGENnomLabel.Visible = 'on';
                app.SOCstartLabel.Visible = 'on';
                app.SOCstopLabel.Visible = 'on';
            else
                app.b0iLabel.Visible = 'off';
                app.b1iLabel.Visible = 'off';
                app.b0sLabel.Visible = 'off';
                app.b1sLabel.Visible = 'off';
                app.PGENnomLabel.Visible = 'off';
                app.SOCstartLabel.Visible = 'off';
                app.SOCstopLabel.Visible = 'off';
            end
            
        end

        % Value changed function: HELPSite
        function HELPSiteValueChanged(app, event)
            if (app.HELPSite.Value=="On")
                app.LatitudeLabel.Visible = 'on';
                app.StLongitudeLabel.Visible = 'on';
                app.LongitudeLabel.Visible = 'on';
                app.AltitudeLabel.Visible = 'on';
            else
                app.LatitudeLabel.Visible = 'off';
                app.StLongitudeLabel.Visible = 'off';
                app.LongitudeLabel.Visible = 'off';
                app.AltitudeLabel.Visible = 'off';
                
            end
        end

        % Value changed function: HELPMeteo
        function HELPMeteoValueChanged(app, event)
             if (app.HELPMeteo.Value=="On")
                 app.Gdm0Label1.Visible = 'on';
                 app.Gdm0Label2.Visible = 'on';
                 app.TmmLabel.Visible = 'on';
                 app.TMmLabel.Visible = 'on';
             else
                 app.Gdm0Label1.Visible = 'off';
                 app.Gdm0Label2.Visible = 'off';
                 app.TmmLabel.Visible = 'off';
                 app.TMmLabel.Visible = 'off';
             end
            
        end

        % Value changed function: HELPPVgen
        function HELPPVgenValueChanged(app, event)
             if (app.HELPPVgen.Value=="On")
                 app.RthLabel1.Visible = 'on';
                 app.RthLabel2.Visible = 'on';
                 app.NOCTLabel.Visible = 'on';
                 app.PVnomLabel.Visible = 'on';
                 app.CVPTLabel.Visible = 'on';
             else
                 app.RthLabel1.Visible = 'off';
                 app.RthLabel2.Visible = 'off';
                 app.NOCTLabel.Visible = 'off';
                 app.PVnomLabel.Visible = 'off';
                 app.CVPTLabel.Visible = 'off';
             end
        end

        % Value changed function: HELPBattery
        function HELPBatteryValueChanged(app, event)
             if (app.HELPBattery.Value=="On")
                 app.CBATLabel.Visible = 'on';
                 app.SOCmaxLabel.Visible = 'on';
                 app.SOCminLabel.Visible = 'on';
             else
                 app.CBATLabel.Visible = 'off';
                 app.SOCmaxLabel.Visible = 'off';
                 app.SOCminLabel.Visible = 'off';
                 
             end
        end

        % Value changed function: HELPLoad
        function HELPLoadValueChanged(app, event)
            if (app.HELPLoad.Value=="On")
                app.LdmLabel.Visible = 'on';
                app.FhLabel1.Visible = 'on';
                app.FhLabel2.Visible = 'on';
            else
                app.LdmLabel.Visible = 'off';
                app.FhLabel1.Visible = 'off';
                app.FhLabel2.Visible = 'off';
            end
            
        end

        % Value changed function: PWnomI
        function PWnomIValueChanged(app, event)
            assignin("base","PWnom",app.PWnomI.Value);
            if(app.PWnomI.Value==0)
                app.CpeqI.Value = 0;
            else
                app.CpeqI.Value = app.PWnomI.Value/(app.VnomI.Value^3-app.VciI.Value^3);
            end
            app.PowerCurve.Data=[0 0; app.VciI.Value 0;...
                app.VciI.Value+0.2*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.2*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VciI.Value+0.4*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.4*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VciI.Value+0.6*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.6*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VciI.Value+0.8*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.8*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VnomI.Value app.PWnomI.Value; app.VcoI.Value app.PWnomI.Value; app.VcoI.Value 0];
            plot(app.PowerCurveGraph,app.PowerCurve.Data(1:9,1),app.PowerCurve.Data(1:9,2));
        end

        % Value changed function: VnomI
        function VnomIValueChanged(app, event)
            assignin("base","Vnom",app.VnomI.Value);
            if(app.VnomI.Value==0)
                app.CpeqI.Value = 0;
            else
                app.CpeqI.Value = app.PWnomI.Value/(app.VnomI.Value^3-app.VciI.Value^3);
            end
            app.PowerCurve.Data=[0 0; app.VciI.Value 0;...
                app.VciI.Value+0.2*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.2*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VciI.Value+0.4*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.4*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VciI.Value+0.6*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.6*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VciI.Value+0.8*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.8*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VnomI.Value app.PWnomI.Value; app.VcoI.Value app.PWnomI.Value; app.VcoI.Value 0];
            plot(app.PowerCurveGraph,app.PowerCurve.Data(1:9,1),app.PowerCurve.Data(1:9,2));
        end

        % Value changed function: VciI
        function VciIValueChanged(app, event)
            assignin("base","Vci",app.VciI.Value);
            if(app.VciI.Value==0)
                app.CpeqI.Value = 0;
            else
                app.CpeqI.Value = app.PWnomI.Value/(app.VnomI.Value^3-app.VciI.Value^3);
            end
            app.PowerCurve.Data=[0 0; app.VciI.Value 0;...
                app.VciI.Value+0.2*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.2*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VciI.Value+0.4*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.4*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VciI.Value+0.6*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.6*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VciI.Value+0.8*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.8*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VnomI.Value app.PWnomI.Value; app.VcoI.Value app.PWnomI.Value; app.VcoI.Value 0];
            plot(app.PowerCurveGraph,app.PowerCurve.Data(1:9,1),app.PowerCurve.Data(1:9,2));
        end

        % Value changed function: VcoI
        function VcoIValueChanged(app, event)
            assignin("base","Vco",app.VcoI.Value);
            app.PowerCurve.Data=[0 0; app.VciI.Value 0;...
                app.VciI.Value+0.2*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.2*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VciI.Value+0.4*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.4*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VciI.Value+0.6*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.6*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VciI.Value+0.8*(app.VnomI.Value-app.VciI.Value) app.CpeqI.Value*((app.VciI.Value+0.8*(app.VnomI.Value-app.VciI.Value))^3-app.VciI.Value^3);...
                app.VnomI.Value app.PWnomI.Value; app.VcoI.Value app.PWnomI.Value; app.VcoI.Value 0];
            plot(app.PowerCurveGraph,app.PowerCurve.Data(1:9,1),app.PowerCurve.Data(1:9,2));
        end

        % Value changed function: HELPWind
        function HELPWindValueChanged(app, event)
            if(app.HELPWind.Value=="On")
                app.PWnomLabel.Visible = 'on';
                app.VnomLabel.Visible = 'on';
                app.VciLabel.Visible = 'on';
                app.VcoLabel.Visible = 'on';
                app.CpeqLabel.Visible = 'on';
            else
                app.PWnomLabel.Visible = 'off';
                app.VnomLabel.Visible = 'off';
                app.VciLabel.Visible = 'off';
                app.VcoLabel.Visible = 'off';
                app.CpeqLabel.Visible = 'off';         
            end
        end

        % Value changed function: QratedI
        function QratedIValueChanged(app, event)
            assignin("base","Qrated",app.QratedI.Value);
            
            if(app.HfI.Value==0 || app.QratedI.Value==0)
                 app.ks2I.Value=app.kw2I.Value;
            else
                 app.ks2I.Value=(app.HfI.Value/(app.QratedI.Value^2))+app.kw2I.Value;
            end
            
            app.systemCurveTable.Data=[0 app.ks0I.Value;...
                0.15*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.15*app.QratedI.Value+app.ks2I.Value*(0.15*app.QratedI.Value)^2;...
                0.3*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.3*app.QratedI.Value+app.ks2I.Value*(0.3*app.QratedI.Value)^2;...
                0.45*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.45*app.QratedI.Value+app.ks2I.Value*(0.45*app.QratedI.Value)^2;...
                0.6*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.6*app.QratedI.Value+app.ks2I.Value*(0.6*app.QratedI.Value)^2;...
                0.75*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.75*app.QratedI.Value+app.ks2I.Value*(0.75*app.QratedI.Value)^2;...
                0.9*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.9*app.QratedI.Value+app.ks2I.Value*(0.9*app.QratedI.Value)^2;...
                1.05*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.05*app.QratedI.Value+app.ks2I.Value*(1.05*app.QratedI.Value)^2;...
                1.2*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.2*app.QratedI.Value+app.ks2I.Value*(1.2*app.QratedI.Value)^2;...
                1.35*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.35*app.QratedI.Value+app.ks2I.Value*(1.35*app.QratedI.Value)^2];
             plot(app.systemCurveGraph,app.systemCurveTable.Data(1:10,1),app.systemCurveTable.Data(1:10,2));
            
        end

        % Value changed function: HratedI
        function HratedIValueChanged(app, event)
            assignin("base","Hrated",app.HratedI.Value);
        end

        % Value changed function: LiquidDensityI
        function LiquidDensityIValueChanged(app, event)
            assignin("base","Density",app.LiquidDensityI.Value);
        end

        % Value changed function: ks1I
        function ks1IValueChanged(app, event)
            app.systemCurveTable.Data=[0 app.ks0I.Value;...
                0.15*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.15*app.QratedI.Value+app.ks2I.Value*(0.15*app.QratedI.Value)^2;...
                0.3*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.3*app.QratedI.Value+app.ks2I.Value*(0.3*app.QratedI.Value)^2;...
                0.45*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.45*app.QratedI.Value+app.ks2I.Value*(0.45*app.QratedI.Value)^2;...
                0.6*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.6*app.QratedI.Value+app.ks2I.Value*(0.6*app.QratedI.Value)^2;...
                0.75*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.75*app.QratedI.Value+app.ks2I.Value*(0.75*app.QratedI.Value)^2;...
                0.9*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.9*app.QratedI.Value+app.ks2I.Value*(0.9*app.QratedI.Value)^2;...
                1.05*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.05*app.QratedI.Value+app.ks2I.Value*(1.05*app.QratedI.Value)^2;...
                1.2*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.2*app.QratedI.Value+app.ks2I.Value*(1.2*app.QratedI.Value)^2;...
                1.35*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.35*app.QratedI.Value+app.ks2I.Value*(1.35*app.QratedI.Value)^2];
             plot(app.systemCurveGraph,app.systemCurveTable.Data(1:10,1),app.systemCurveTable.Data(1:10,2));
            
        end

        % Value changed function: HfI
        function HfIValueChanged(app, event)
            assignin("base","Hf",app.HfI.Value);
            
            if(app.HfI.Value==0 || app.QratedI.Value==0)
                 app.ks2I.Value=app.kw2I.Value;
             else
                 app.ks2I.Value=(app.HfI.Value/(app.QratedI.Value^2))+app.kw2I.Value;
            end
            
            app.systemCurveTable.Data=[0 app.ks0I.Value;...
                0.15*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.15*app.QratedI.Value+app.ks2I.Value*(0.15*app.QratedI.Value)^2;...
                0.3*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.3*app.QratedI.Value+app.ks2I.Value*(0.3*app.QratedI.Value)^2;...
                0.45*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.45*app.QratedI.Value+app.ks2I.Value*(0.45*app.QratedI.Value)^2;...
                0.6*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.6*app.QratedI.Value+app.ks2I.Value*(0.6*app.QratedI.Value)^2;...
                0.75*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.75*app.QratedI.Value+app.ks2I.Value*(0.75*app.QratedI.Value)^2;...
                0.9*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.9*app.QratedI.Value+app.ks2I.Value*(0.9*app.QratedI.Value)^2;...
                1.05*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.05*app.QratedI.Value+app.ks2I.Value*(1.05*app.QratedI.Value)^2;...
                1.2*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.2*app.QratedI.Value+app.ks2I.Value*(1.2*app.QratedI.Value)^2;...
                1.35*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.35*app.QratedI.Value+app.ks2I.Value*(1.35*app.QratedI.Value)^2];
              plot(app.systemCurveGraph,app.systemCurveTable.Data(1:10,1),app.systemCurveTable.Data(1:10,2));
              
        end

        % Cell edit callback: pumpCurveTable
        function pumpCurveTableCellEdit(app, event)
            assignin("base","PumpCurve",app.pumpCurveTable.Data);
            plot(app.pumpCurveGraph,app.pumpCurveTable.Data(1:7,1),app.pumpCurveTable.Data(1:7,2));
        end

        % Cell edit callback: powerCurvesTable
        function powerCurvesTableCellEdit(app, event)
            assignin("base","PowerCurves",app.powerCurvesTable.Data);
            plot(app.powerCurvesGraph,app.powerCurvesTable.Data(1:7,1),app.powerCurvesTable.Data(1:7,2), "m");
            plot(app.powerCurvesGraph,app.powerCurvesTable.Data(1:7,1),app.powerCurvesTable.Data(1:7,3), "b");
            
        end

        % Value changed function: P2ratedI
        function P2ratedIValueChanged(app, event)
            assignin("base","P2rated",app.P2ratedI.Value);
        end

        % Value changed function: RPMnomI
        function RPMnomIValueChanged(app, event)
            assignin("base","RPMnom",app.RPMnomI.Value);
        end

        % Value changed function: RPMcoolI
        function RPMcoolIValueChanged(app, event)
            assignin("base","RPMcoolM",app.RPMcoolI.Value);
        end

        % Value changed function: RPMmaxI
        function RPMmaxIValueChanged(app, event)
            assignin("base","RPMmaxM",app.RPMmaxI.Value);
        end

        % Cell edit callback: PowerEfficTable
        function PowerEfficTableCellEdit(app, event)
            assignin("base","PowerEffic",app.PowerEfficTable.Data);
            plot(app.PowerEfficGraph,app.PowerEfficTable.Data(1:7,1),app.PowerEfficTable.Data(1:7,2));
           
        end

        % Value changed function: HELPInverter
        function HELPInverterValueChanged(app, event)
            if(app.HELPInverter.Value=="On")
                app.NominaloutputLabel.Visible = 'on';
                app.MaximumoutputLabel.Visible = 'on';
                app.efficiencyLabel1.Visible = 'on';
                app.efficiencyLabel2.Visible = 'on';
                app.efficiencyLabel3.Visible = 'on';
                app.efficiencyLabel4.Visible = 'on';
                app.k0Label.Visible = 'on';
                app.k1Label.Visible = 'on';
                app.k2Label.Visible = 'on';
                app.pacLabel.Visible = 'on';
                app.EfficiencyLabel.Visible = 'on';
            else
                app.NominaloutputLabel.Visible = 'off';
                app.MaximumoutputLabel.Visible = 'off';
                app.efficiencyLabel1.Visible = 'off';
                app.efficiencyLabel2.Visible = 'off';
                app.efficiencyLabel3.Visible = 'off';
                app.efficiencyLabel4.Visible = 'off';
                app.k0Label.Visible = 'off';
                app.k1Label.Visible = 'off';
                app.k2Label.Visible = 'off';
                app.pacLabel.Visible = 'off';
                app.EfficiencyLabel.Visible = 'off';
            end
        end

        % Value changed function: HELPPump2
        function HELPPump2ValueChanged(app, event)
            if(app.HELPPump2.Value=="On")
                app.ks0Label.Visible='on';
                app.ks1Label.Visible='on';
                app.ks2Label.Visible='on';
                app.HfLabel.Visible='on';
            else
                app.ks0Label.Visible='off';
                app.ks1Label.Visible='off';
                app.ks2Label.Visible='off';
                app.HfLabel.Visible='off';
            end
            
        end

        % Value changed function: HELPPump3
        function HELPPump3ValueChanged(app, event)
            if(app.HELPPump3.Value=="On")
                app.P1Label1.Visible = 'on';
                app.P1Label2.Visible = 'on';
                app.P2Label1.Visible = 'on';
                app.P2Label2.Visible = 'on';
            else
                app.P1Label1.Visible = 'off';
                app.P1Label2.Visible = 'off';
                app.P2Label1.Visible = 'off';
                app.P2Label2.Visible = 'off';
            end
        end

        % Value changed function: HELPPump4
        function HELPPump4ValueChanged(app, event)
            if(app.HELPPump4.Value=="On")
                app.P2RatedLabel.Visible = 'on';
                app.RPMnomLabel.Visible = 'on';
                app.RPMcoolLabel.Visible = 'on';
                app.RPMmaxLabel.Visible = 'on';
            else
                app.P2RatedLabel.Visible = 'off';
                app.RPMnomLabel.Visible = 'off';
                app.RPMcoolLabel.Visible = 'off';
                app.RPMmaxLabel.Visible = 'off';
            end
        end

        % Value changed function: SaveData
        function SaveDataValueChanged(app, event)
            if(app.SaveData.Value==0)
                assignin("base","saveDataApp",0);
            else
                assignin("base","saveDataApp",1);
            end
        end

        % Value changed function: nameI
        function nameIValueChanged(app, event)
            assignin("base","name", app.nameI.Value);
        end

        % Selection changed function: MOUNTING
        function MOUNTINGSelectionChanged(app, event)
           if(app.Staticground.Value==true)
               assignin("base","Mounting",1);
           elseif(app.Staticdelta.Value==true)
                assignin("base","Mounting",2);
           elseif(app.TrackerHorizontal.Value==true)
                assignin("base","Mounting",3);
           elseif(app.TrackerVertical.Value==true)
                assignin("base","Mounting",4);
           else
                assignin("base","Mounting",5);
           end
            
        end

        % Value changed function: inclinationI_delta
        function inclinationI_deltaValueChanged(app, event)
            assignin("base","InclinationDelta",app.inclinationI_delta.Value);
            
        end

        % Value changed function: inclinationI_tracking
        function inclinationI_trackingValueChanged(app, event)
            assignin("base","InclinationTracking",app.inclinationI_tracking.Value);
            
        end

        % Value changed function: HELPPVgen_2
        function HELPPVgen_2ValueChanged(app, event)
            if(app.HELPPVgen_2.Value=="On")
                app.InclinationStaticLabel.Visible = 'on';
                app.OrientationLabel1.Visible = 'on';
                app.OrientationLabel2.Visible = 'on';
                app.InclinationDeltaLabel1.Visible = 'on';
                app.InclinationDeltaLabel2.Visible = 'on';
                app.InclinationTrackingLabel.Visible = 'on';
            else
                app.InclinationStaticLabel.Visible = 'off';
                app.OrientationLabel1.Visible = 'off';
                app.OrientationLabel2.Visible = 'off';
                app.InclinationDeltaLabel1.Visible = 'off';
                app.InclinationDeltaLabel2.Visible = 'off';
                app.InclinationTrackingLabel.Visible = 'off';
            end
           
        end

        % Value changed function: HsI
        function HsIValueChanged(app, event)
            assignin("base","Hs",app.HsI.Value);
            app.ks0I.Value = app.HrI.Value + app.HsI.Value;
            app.systemCurveTable.Data=[0 app.ks0I.Value;...
                0.15*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.15*app.QratedI.Value+app.ks2I.Value*(0.15*app.QratedI.Value)^2;...
                0.3*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.3*app.QratedI.Value+app.ks2I.Value*(0.3*app.QratedI.Value)^2;...
                0.45*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.45*app.QratedI.Value+app.ks2I.Value*(0.45*app.QratedI.Value)^2;...
                0.6*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.6*app.QratedI.Value+app.ks2I.Value*(0.6*app.QratedI.Value)^2;...
                0.75*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.75*app.QratedI.Value+app.ks2I.Value*(0.75*app.QratedI.Value)^2;...
                0.9*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.9*app.QratedI.Value+app.ks2I.Value*(0.9*app.QratedI.Value)^2;...
                1.05*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.05*app.QratedI.Value+app.ks2I.Value*(1.05*app.QratedI.Value)^2;...
                1.2*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.2*app.QratedI.Value+app.ks2I.Value*(1.2*app.QratedI.Value)^2;...
                1.35*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.35*app.QratedI.Value+app.ks2I.Value*(1.35*app.QratedI.Value)^2];
             plot(app.systemCurveGraph,app.systemCurveTable.Data(1:10,1),app.systemCurveTable.Data(1:10,2));
            
        end

        % Value changed function: QtestI
        function QtestIValueChanged(app, event)
            assignin("base","Qtest",app.QtestI.Value);
            app.kw1I.Value=app.HdrI.Value/app.QtestI.Value;
            assignin("base","kw1",app.kw1I.Value);
        end

        % Value changed function: HdrI
        function HdrIValueChanged(app, event)
            assignin("base","Hdr",app.HdrI.Value);
            app.kw1I.Value=app.HdrI.Value/app.QtestI.Value;
            assignin("base","kw1",app.kw1I.Value);
        end

        % Value changed function: kw1I
        function kw1IValueChanged(app, event)
            assignin("base","kw1",app.kw1I.Value);
        end

        % Value changed function: kw2I
        function kw2IValueChanged(app, event)
            assignin("base","kw2",app.kw2I.Value);
            if(app.HfI.Value==0 || app.QratedI.Value==0)
                 app.ks2I.Value=app.kw2I.Value;
            else
                 app.ks2I.Value=(app.HfI.Value/(app.QratedI.Value^2))+app.kw2I.Value;
            end
            app.systemCurveTable.Data=[0 app.ks0I.Value;...
                0.15*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.15*app.QratedI.Value+app.ks2I.Value*(0.15*app.QratedI.Value)^2;...
                0.3*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.3*app.QratedI.Value+app.ks2I.Value*(0.3*app.QratedI.Value)^2;...
                0.45*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.45*app.QratedI.Value+app.ks2I.Value*(0.45*app.QratedI.Value)^2;...
                0.6*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.6*app.QratedI.Value+app.ks2I.Value*(0.6*app.QratedI.Value)^2;...
                0.75*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.75*app.QratedI.Value+app.ks2I.Value*(0.75*app.QratedI.Value)^2;...
                0.9*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.9*app.QratedI.Value+app.ks2I.Value*(0.9*app.QratedI.Value)^2;...
                1.05*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.05*app.QratedI.Value+app.ks2I.Value*(1.05*app.QratedI.Value)^2;...
                1.2*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.2*app.QratedI.Value+app.ks2I.Value*(1.2*app.QratedI.Value)^2;...
                1.35*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.35*app.QratedI.Value+app.ks2I.Value*(1.35*app.QratedI.Value)^2];
             plot(app.systemCurveGraph,app.systemCurveTable.Data(1:10,1),app.systemCurveTable.Data(1:10,2));
            
        end

        % Value changed function: WTCI
        function WTCIValueChanged(app, event)
            assignin("base","WTC",app.WTCI.Value);
        end

        % Value changed function: HrI
        function HrIValueChanged(app, event)
            assignin("base","Hr",app.HrI.Value);
            app.ks0I.Value = app.HrI.Value + app.HsI.Value;
            app.systemCurveTable.Data=[0 app.ks0I.Value;...
                0.15*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.15*app.QratedI.Value+app.ks2I.Value*(0.15*app.QratedI.Value)^2;...
                0.3*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.3*app.QratedI.Value+app.ks2I.Value*(0.3*app.QratedI.Value)^2;...
                0.45*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.45*app.QratedI.Value+app.ks2I.Value*(0.45*app.QratedI.Value)^2;...
                0.6*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.6*app.QratedI.Value+app.ks2I.Value*(0.6*app.QratedI.Value)^2;...
                0.75*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.75*app.QratedI.Value+app.ks2I.Value*(0.75*app.QratedI.Value)^2;...
                0.9*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*0.9*app.QratedI.Value+app.ks2I.Value*(0.9*app.QratedI.Value)^2;...
                1.05*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.05*app.QratedI.Value+app.ks2I.Value*(1.05*app.QratedI.Value)^2;...
                1.2*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.2*app.QratedI.Value+app.ks2I.Value*(1.2*app.QratedI.Value)^2;...
                1.35*app.QratedI.Value app.ks0I.Value+app.ks1I.Value*1.35*app.QratedI.Value+app.ks2I.Value*(1.35*app.QratedI.Value)^2];
             plot(app.systemCurveGraph,app.systemCurveTable.Data(1:10,1),app.systemCurveTable.Data(1:10,2));
            
        end

        % Value changed function: HELPPump1
        function HELPPump1ValueChanged(app, event)
            if(app.HELPPump1.Value=="On")
                app.HsLabel.Visible = 'on';
                app.QtestLabel.Visible = 'on';
                app.HdrLabel.Visible = 'on';
                app.kw1Label1.Visible = 'on';
                app.kw1Label2.Visible = 'on';
                app.kw2Label.Visible = 'on';
                app.WTCLabel.Visible = 'on';
                app.WTCLabel2.Visible = 'on';
                app.HrLabel.Visible = 'on';
                app.QratedLabel.Visible = 'on';
                app.HratedLabel.Visible = 'on';
                app.densityLabel.Visible = 'on';
            else
                app.HsLabel.Visible = 'off';
                app.QtestLabel.Visible = 'off';
                app.HdrLabel.Visible = 'off';
                app.kw1Label1.Visible = 'off';
                app.kw1Label2.Visible = 'off';
                app.kw2Label.Visible = 'off';
                app.WTCLabel.Visible = 'off';
                app.WTCLabel2.Visible = 'off';
                app.HrLabel.Visible = 'off';
                app.QratedLabel.Visible = 'off';
                app.HratedLabel.Visible = 'off';
                app.densityLabel.Visible = 'off';
            end
            
        end

        % Value changed function: inclinationGround
        function inclinationGroundValueChanged(app, event)
            assignin("base","InclinationGround",app.inclinationGround.Value);
        end

        % Button pushed function: loadProject
        function loadProjectButtonPushed(app, event)
            startupFcn(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Site = uitab(app.TabGroup);
            app.Site.Title = 'Site';
            app.GEOGRAPHICALDATALabel = uilabel(app.Site);
            app.GEOGRAPHICALDATALabel.Text = 'GEOGRAPHICAL DATA';
            app.LatitudeDegreeLabel = uilabel(app.Site);
            app.LatitudeDegreeLabel.Text = 'Latitude (Degree)';
            app.LatitudeI = uieditfield(app.Site, 'numeric');
            app.LatitudeI.ValueChangedFcn = createCallbackFcn(app, @LatitudeIValueChanged, true);
            app.LongitudeDegreeLabel = uilabel(app.Site);
            app.LongitudeDegreeLabel.Text = 'Longitude (Degree)';
            app.LongitudeI = uieditfield(app.Site, 'numeric');
            app.LongitudeI.ValueChangedFcn = createCallbackFcn(app, @LongitudeIValueChanged, true);
            app.AltitudemeterLabel = uilabel(app.Site);
            app.AltitudemeterLabel.Text = 'Altitude (meter)';
            app.AltitudeI = uieditfield(app.Site, 'numeric');
            app.AltitudeI.ValueChangedFcn = createCallbackFcn(app, @AltitudeIValueChanged, true);
            app.StandardLongitudeDegreeLabel = uilabel(app.Site);
            app.StandardLongitudeDegreeLabel.Text = 'Standard Longitude (Degree)';
            app.StandardLongitudeI = uispinner(app.Site);
            app.StandardLongitudeI.Step = 15;
            app.StandardLongitudeI.ValueChangedFcn = createCallbackFcn(app, @StandardLongitudeIValueChanged, true);
            app.LatitudeLabel = uilabel(app.Site);
            app.LatitudeLabel.Visible = 'off';
            app.LatitudeLabel.Text = 'Latitude of the location, positive in the Northern Hemisphere and negative in the Southern Hemisphere.';
            app.StLongitudeLabel = uilabel(app.Site);
            app.StLongitudeLabel.Visible = 'off';
            app.StLongitudeLabel.Text = 'Standard longitude (multiple of 15º). Negative towards West and positive towards East.';
            app.LongitudeLabel = uilabel(app.Site);
            app.LongitudeLabel.Visible = 'off';
            app.LongitudeLabel.Text = 'Longitude of the location, negative towards West and positive towards Est.';
            app.AltitudeLabel = uilabel(app.Site);
            app.AltitudeLabel.Visible = 'off';
            app.AltitudeLabel.Text = 'Altitude of the location over sea level.';
            app.HELPSwitchLabel_2 = uilabel(app.Site);
            app.HELPSwitchLabel_2.Text = 'HELP';
            app.HELPSite = uiswitch(app.Site, 'slider');
            app.HELPSite.ValueChangedFcn = createCallbackFcn(app, @HELPSiteValueChanged, true);
            app.Meteo = uitab(app.TabGroup);
            app.Meteo.Title = 'Meteo';
            app.MeteorologicalinputdataListBoxLabel = uilabel(app.Meteo);
            app.MeteorologicalinputdataListBoxLabel.Text = 'Meteorological input data';
            app.InputDataI = uilistbox(app.Meteo);
            app.InputDataI.Items = {'Monthly averages from the table', 'Monthly averages obtained from PVGIS TMY', 'Hourly values from PVGIS TMY', 'Monthly averages obtained from USA TMY3', 'Hourly values from USA TMY3'};
            app.InputDataI.ValueChangedFcn = createCallbackFcn(app, @InputDataIValueChanged, true);
            app.InputDataI.Value = 'Monthly averages from the table';
            app.TimeSeriesDropDownLabel = uilabel(app.Meteo);
            app.TimeSeriesDropDownLabel.Text = 'Time Series';
            app.TimeSeriesI = uidropdown(app.Meteo);
            app.TimeSeriesI.Items = {'Mean Days', 'Aguiar'};
            app.TimeSeriesI.ValueChangedFcn = createCallbackFcn(app, @TimeSeriesIValueChanged, true);
            app.TimeSeriesI.Value = 'Mean Days';
            app.METEOROLOGICALINPUTDATALabel = uilabel(app.Meteo);
            app.METEOROLOGICALINPUTDATALabel.Text = 'METEOROLOGICAL INPUT DATA';
            app.MetDataI = uitable(app.Meteo);
            app.MetDataI.ColumnName = {'Gdm0 [Wh/m2]'; 'Tmm [ºC]'; 'TMm [ºC]'};
            app.MetDataI.RowName = {'January'; 'February'; 'March'; 'April'; 'May'; 'June'; 'July'; 'August'; 'September'; 'October'; 'November'; 'December'};
            app.MetDataI.ColumnEditable = true;
            app.MetDataI.CellEditCallback = createCallbackFcn(app, @MetDataICellEdit, true);
            app.Gdm0Label1 = uilabel(app.Meteo);
            app.Gdm0Label1.Visible = 'off';
            app.Gdm0Label1.Text = 'Gdm0: Monthly average of daily global  horizontal';
            app.Gdm0Label2 = uilabel(app.Meteo);
            app.Gdm0Label2.Visible = 'off';
            app.Gdm0Label2.Text = ' irradiation.';
            app.TmmLabel = uilabel(app.Meteo);
            app.TmmLabel.Visible = 'off';
            app.TmmLabel.Text = 'Tmm: Monthly average of ninimum daily temperature.';
            app.TMmLabel = uilabel(app.Meteo);
            app.TMmLabel.Visible = 'off';
            app.TMmLabel.Text = 'TMm: Monthly average of maximum daily temperature.';
            app.HELPSwitchLabel_3 = uilabel(app.Meteo);
            app.HELPSwitchLabel_3.Text = 'HELP';
            app.HELPMeteo = uiswitch(app.Meteo, 'slider');
            app.HELPMeteo.ValueChangedFcn = createCallbackFcn(app, @HELPMeteoValueChanged, true);
            app.PVgenerator = uitab(app.TabGroup);
            app.PVgenerator.Title = 'PVgen (1)';
            app.PVnomkWpEditFieldLabel = uilabel(app.PVgenerator);
            app.PVnomkWpEditFieldLabel.Text = 'PVnom (kWp)';
            app.PVnomI = uieditfield(app.PVgenerator, 'numeric');
            app.PVnomI.ValueChangedFcn = createCallbackFcn(app, @PVnomIValueChanged, true);
            app.CVPTC1Label = uilabel(app.PVgenerator);
            app.CVPTC1Label.Text = 'CVPT (%·ºC^-1)';
            app.CVPTI = uieditfield(app.PVgenerator, 'numeric');
            app.CVPTI.Limits = [0 Inf];
            app.CVPTI.ValueChangedFcn = createCallbackFcn(app, @CVPTIValueChanged, true);
            app.NOCTCLabel = uilabel(app.PVgenerator);
            app.NOCTCLabel.Text = 'NOCT (ºC)';
            app.NOCTI = uieditfield(app.PVgenerator, 'numeric');
            app.NOCTI.ValueChangedFcn = createCallbackFcn(app, @NOCTIValueChanged, true);
            app.NOCTLabel = uilabel(app.PVgenerator);
            app.NOCTLabel.Visible = 'off';
            app.NOCTLabel.Text = 'Nominal Operation Cell Temperature';
            app.ThermalresistanceCm2WLabel = uilabel(app.PVgenerator);
            app.ThermalresistanceCm2WLabel.Text = 'Thermal resistance (ºC·m2/W)';
            app.RthI = uieditfield(app.PVgenerator, 'numeric');
            app.RthI.ValueChangedFcn = createCallbackFcn(app, @RthIValueChanged, true);
            app.PVnomLabel = uilabel(app.PVgenerator);
            app.PVnomLabel.Visible = 'off';
            app.PVnomLabel.Text = 'Nominal PV power';
            app.CVPTLabel = uilabel(app.PVgenerator);
            app.CVPTLabel.Visible = 'off';
            app.CVPTLabel.Text = 'Coefficient of Variation of PV module Power with Temperature (absolute value)';
            app.RthLabel1 = uilabel(app.PVgenerator);
            app.RthLabel1.Visible = 'off';
            app.RthLabel1.Text = 'Thermal resistance. This parameter is calculated as [NOCT-20]/800, but other values  ';
            app.RthLabel2 = uilabel(app.PVgenerator);
            app.RthLabel2.Visible = 'off';
            app.RthLabel2.Text = 'can be chosen depending on the mounting and ventilation of PV modules.';
            app.PVGENERATOR12Label = uilabel(app.PVgenerator);
            app.PVGENERATOR12Label.Text = 'PV GENERATOR (1/2)';
            app.HELPSwitchLabel_4 = uilabel(app.PVgenerator);
            app.HELPSwitchLabel_4.Text = 'HELP';
            app.HELPPVgen = uiswitch(app.PVgenerator, 'slider');
            app.HELPPVgen.ValueChangedFcn = createCallbackFcn(app, @HELPPVgenValueChanged, true);
            app.PVgenenerator2 = uitab(app.TabGroup);
            app.PVgenenerator2.Title = 'PVgen (2)';
            app.InclinationDegreeLabel = uilabel(app.PVgenenerator2);
            app.InclinationDegreeLabel.Text = 'Inclination (Degree)';
            app.inclinationGround = uieditfield(app.PVgenenerator2, 'numeric');
            app.inclinationGround.Limits = [0 90];
            app.inclinationGround.ValueChangedFcn = createCallbackFcn(app, @inclinationGroundValueChanged, true);
            app.InclinationStaticLabel = uilabel(app.PVgenenerator2);
            app.InclinationStaticLabel.Visible = 'off';
            app.InclinationStaticLabel.Text = 'Inclination of the modules regarding the horizontal (0º to 90º)';
            app.OrientationDegreeLabel = uilabel(app.PVgenenerator2);
            app.OrientationDegreeLabel.Text = 'Orientation (Degree)';
            app.orientationI = uieditfield(app.PVgenenerator2, 'numeric');
            app.orientationI.ValueChangedFcn = createCallbackFcn(app, @orientationIValueChanged, true);
            app.OrientationLabel1 = uilabel(app.PVgenenerator2);
            app.OrientationLabel1.Visible = 'off';
            app.OrientationLabel1.Text = 'Orientation of the modules towards the Equator. Zero towards the South in the Northern Hemisphere ';
            app.OrientationLabel2 = uilabel(app.PVgenenerator2);
            app.OrientationLabel2.Visible = 'off';
            app.OrientationLabel2.Text = '(North in the Southern Hemisphere), negative towards the East, and positive towards the West.';
            app.PVGENERATOR22Label = uilabel(app.PVgenenerator2);
            app.PVGENERATOR22Label.Text = 'PV GENERATOR (2/2)';
            app.MOUNTINGSTRUCTURELabel = uilabel(app.PVgenenerator2);
            app.MOUNTINGSTRUCTURELabel.Text = 'MOUNTING STRUCTURE';
            app.MOUNTING = uibuttongroup(app.PVgenenerator2);
            app.MOUNTING.SelectionChangedFcn = createCallbackFcn(app, @MOUNTINGSelectionChanged, true);
            app.MOUNTING.Title = 'Mounting structure';
            app.Staticground = uiradiobutton(app.MOUNTING);
            app.Staticground.Text = 'Static: ground or roof';
            app.Staticground.Value = true;
            app.Staticdelta = uiradiobutton(app.MOUNTING);
            app.Staticdelta.Text = 'Static: delta';
            app.TrackerHorizontal = uiradiobutton(app.MOUNTING);
            app.TrackerHorizontal.Text = 'Tracker: One axis N-S horizontal';
            app.TrackerVertical = uiradiobutton(app.MOUNTING);
            app.TrackerVertical.Text = 'Tracker: One axis vertical (azimutal)';
            app.TrackerTwo = uiradiobutton(app.MOUNTING);
            app.TrackerTwo.Text = 'Tracker: Two axis';
            app.StaticgroundorroofLabel = uilabel(app.PVgenenerator2);
            app.StaticgroundorroofLabel.Text = 'Static ground or roof';
            app.StaticdeltaLabel = uilabel(app.PVgenenerator2);
            app.StaticdeltaLabel.Text = 'Static delta';
            app.AzimutaltrackingLabel = uilabel(app.PVgenenerator2);
            app.AzimutaltrackingLabel.Text = 'Azimutal tracking';
            app.InclinationDegreeLabel_2 = uilabel(app.PVgenenerator2);
            app.InclinationDegreeLabel_2.Text = 'Inclination (Degree)';
            app.inclinationI_delta = uieditfield(app.PVgenenerator2, 'numeric');
            app.inclinationI_delta.Limits = [0 90];
            app.inclinationI_delta.ValueChangedFcn = createCallbackFcn(app, @inclinationI_deltaValueChanged, true);
            app.InclinationDegreeLabel_3 = uilabel(app.PVgenenerator2);
            app.InclinationDegreeLabel_3.Text = 'Inclination (Degree)';
            app.inclinationI_tracking = uieditfield(app.PVgenenerator2, 'numeric');
            app.inclinationI_tracking.Limits = [0 90];
            app.inclinationI_tracking.ValueChangedFcn = createCallbackFcn(app, @inclinationI_trackingValueChanged, true);
            app.InclinationDeltaLabel1 = uilabel(app.PVgenenerator2);
            app.InclinationDeltaLabel1.Visible = 'off';
            app.InclinationDeltaLabel1.Text = 'Inclination of the modules regarding the horizontal (0º to 90º)';
            app.InclinationDeltaLabel2 = uilabel(app.PVgenenerator2);
            app.InclinationDeltaLabel2.Visible = 'off';
            app.InclinationDeltaLabel2.Text = 'The same for East and West structures. ';
            app.InclinationTrackingLabel = uilabel(app.PVgenenerator2);
            app.InclinationTrackingLabel.Visible = 'off';
            app.InclinationTrackingLabel.Text = 'Inclination of the modules regarding the horizontal (0º to 90º)';
            app.HELPSwitchLabel_12 = uilabel(app.PVgenenerator2);
            app.HELPSwitchLabel_12.Text = 'HELP';
            app.HELPPVgen_2 = uiswitch(app.PVgenenerator2, 'slider');
            app.HELPPVgen_2.ValueChangedFcn = createCallbackFcn(app, @HELPPVgen_2ValueChanged, true);
            app.Inverter = uitab(app.TabGroup);
            app.Inverter.Title = 'Inverter';
            app.PInomkWEditFieldLabel = uilabel(app.Inverter);
            app.PInomkWEditFieldLabel.Text = 'PInom (kW)';
            app.PInomI = uieditfield(app.Inverter, 'numeric');
            app.PInomI.Limits = [0 Inf];
            app.PInomI.ValueChangedFcn = createCallbackFcn(app, @PInomIValueChanged, true);
            app.PImaxkWEditFieldLabel = uilabel(app.Inverter);
            app.PImaxkWEditFieldLabel.Text = 'PImax (kW)';
            app.PImaxI = uieditfield(app.Inverter, 'numeric');
            app.PImaxI.Limits = [0 Inf];
            app.PImaxI.ValueChangedFcn = createCallbackFcn(app, @PImaxIValueChanged, true);
            app.NominaloutputLabel = uilabel(app.Inverter);
            app.NominaloutputLabel.Visible = 'off';
            app.NominaloutputLabel.Text = 'Nominal output power of the inverter.';
            app.MaximumoutputLabel = uilabel(app.Inverter);
            app.MaximumoutputLabel.Visible = 'off';
            app.MaximumoutputLabel.Text = 'Maximum output power of the inverter.';
            app.InverterCurveI = uibuttongroup(app.Inverter);
            app.InverterCurveI.SelectionChangedFcn = createCallbackFcn(app, @InverterCurveISelectionChanged, true);
            app.InverterCurveI.Title = 'Inverter Curve';
            app.ModelparametersButton = uitogglebutton(app.InverterCurveI);
            app.ModelparametersButton.Text = 'Model parameters';
            app.ModelparametersButton.Value = true;
            app.PowerefficiencycurveButton = uitogglebutton(app.InverterCurveI);
            app.PowerefficiencycurveButton.Text = 'Power efficiency curve';
            app.k0EditFieldLabel = uilabel(app.Inverter);
            app.k0EditFieldLabel.Text = 'k0';
            app.k0I = uieditfield(app.Inverter, 'numeric');
            app.k0I.Limits = [0 Inf];
            app.k0I.ValueChangedFcn = createCallbackFcn(app, @k0IValueChanged, true);
            app.k1EditFieldLabel = uilabel(app.Inverter);
            app.k1EditFieldLabel.Text = 'k1';
            app.k1I = uieditfield(app.Inverter, 'numeric');
            app.k1I.Limits = [0 Inf];
            app.k1I.ValueChangedFcn = createCallbackFcn(app, @k1IValueChanged, true);
            app.k2EditFieldLabel = uilabel(app.Inverter);
            app.k2EditFieldLabel.Text = 'k2';
            app.k2I = uieditfield(app.Inverter, 'numeric');
            app.k2I.Limits = [0 Inf];
            app.k2I.ValueChangedFcn = createCallbackFcn(app, @k2IValueChanged, true);
            app.pointsTable = uitable(app.Inverter);
            app.pointsTable.ColumnName = {'pac'; 'Efficiency'};
            app.pointsTable.RowName = {};
            app.pointsTable.ColumnEditable = true;
            app.pointsTable.CellEditCallback = createCallbackFcn(app, @pointsTableCellEdit, true);
            app.PowerEfgraphic = uiaxes(app.Inverter);
            title(app.PowerEfgraphic, 'Power efficiency curve')
            xlabel(app.PowerEfgraphic, 'pac')
            ylabel(app.PowerEfgraphic, 'Efficiency (%)')
            app.PowerEfgraphic.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.PowerEfgraphic.YTick = [0 20 40 60 80];
            app.PowerEfgraphic.YTickLabel = {'0'; '20'; '40'; '60'; '80'};
            app.INVERTERLabel = uilabel(app.Inverter);
            app.INVERTERLabel.Text = 'INVERTER';
            app.efficiencyLabel1 = uilabel(app.Inverter);
            app.efficiencyLabel1.Visible = 'off';
            app.efficiencyLabel1.Text = 'Definition of the efficiency';
            app.efficiencyLabel2 = uilabel(app.Inverter);
            app.efficiencyLabel2.Visible = 'off';
            app.efficiencyLabel2.Text = '1. Model parameters (k0,k1,k2) ';
            app.efficiencyLabel3 = uilabel(app.Inverter);
            app.efficiencyLabel3.Visible = 'off';
            app.efficiencyLabel3.Text = '2. Points of the power efficiency curve (the program calculates k0, k1 and k2 ';
            app.efficiencyLabel4 = uilabel(app.Inverter);
            app.efficiencyLabel4.Visible = 'off';
            app.efficiencyLabel4.Text = 'using the power efficiency points indicated in the table)  ';
            app.k0Label = uilabel(app.Inverter);
            app.k0Label.Visible = 'off';
            app.k0Label.Text = 'No-load inverter losses.';
            app.k1Label = uilabel(app.Inverter);
            app.k1Label.Visible = 'off';
            app.k1Label.Text = 'Linear inverter losses.';
            app.k2Label = uilabel(app.Inverter);
            app.k2Label.Visible = 'off';
            app.k2Label.Text = 'Joule inverter losses.';
            app.pacLabel = uilabel(app.Inverter);
            app.pacLabel.Visible = 'off';
            app.pacLabel.Text = 'pac (normalised output power): ratio of the output power to the nominal inverter power. ';
            app.EfficiencyLabel = uilabel(app.Inverter);
            app.EfficiencyLabel.Visible = 'off';
            app.EfficiencyLabel.Text = 'Efficiency (power efficiency) = 100*PAC/PDC';
            app.HELPSwitchLabel_8 = uilabel(app.Inverter);
            app.HELPSwitchLabel_8.Text = 'HELP';
            app.HELPInverter = uiswitch(app.Inverter, 'slider');
            app.HELPInverter.ValueChangedFcn = createCallbackFcn(app, @HELPInverterValueChanged, true);
            app.Wiring = uitab(app.TabGroup);
            app.Wiring.Title = 'Wiring';
            app.WDCLabel = uilabel(app.Wiring);
            app.WDCLabel.Text = 'WDC (%)';
            app.WDCI = uieditfield(app.Wiring, 'numeric');
            app.WDCI.Limits = [0 10];
            app.WDCI.ValueChangedFcn = createCallbackFcn(app, @WDCIValueChanged, true);
            app.WACLabel = uilabel(app.Wiring);
            app.WACLabel.Text = 'WAC (%)';
            app.WACI = uieditfield(app.Wiring, 'numeric');
            app.WACI.Limits = [0 10];
            app.WACI.ValueChangedFcn = createCallbackFcn(app, @WACIValueChanged, true);
            app.DCLabel = uilabel(app.Wiring);
            app.DCLabel.Text = 'DC wiring losses (0 to 10%)';
            app.ACLabel = uilabel(app.Wiring);
            app.ACLabel.Text = 'AC wiring losses (0 to 10%)';
            app.WIRINGLabel = uilabel(app.Wiring);
            app.WIRINGLabel.Text = 'WIRING';
            app.Bat = uitab(app.TabGroup);
            app.Bat.Title = 'Battery';
            app.CBATkWhLabel = uilabel(app.Bat);
            app.CBATkWhLabel.Text = 'CBAT (kWh)';
            app.CBATI = uieditfield(app.Bat, 'numeric');
            app.CBATI.ValueChangedFcn = createCallbackFcn(app, @CBATIValueChanged, true);
            app.SOCmaxEditFieldLabel = uilabel(app.Bat);
            app.SOCmaxEditFieldLabel.Text = 'SOCmax';
            app.SOCmaxI = uieditfield(app.Bat, 'numeric');
            app.SOCmaxI.ValueChangedFcn = createCallbackFcn(app, @SOCmaxIValueChanged, true);
            app.SOCminEditFieldLabel = uilabel(app.Bat);
            app.SOCminEditFieldLabel.Text = 'SOCmin';
            app.SOCminI = uieditfield(app.Bat, 'numeric');
            app.SOCminI.ValueChangedFcn = createCallbackFcn(app, @SOCminIValueChanged, true);
            app.SOCmaxLabel = uilabel(app.Bat);
            app.SOCmaxLabel.Visible = 'off';
            app.SOCmaxLabel.Text = 'Maximum state of charge. The PV generator is disconnected when the SOC exceeds this value.';
            app.SOCminLabel = uilabel(app.Bat);
            app.SOCminLabel.Visible = 'off';
            app.SOCminLabel.Text = 'Minimum state of charge. Inverter and loads are disconnected when SOC decreases below this value.';
            app.CBATLabel = uilabel(app.Bat);
            app.CBATLabel.Visible = 'off';
            app.CBATLabel.Text = 'Battery capacity';
            app.BATTERYStandalonePVsystemsLabel = uilabel(app.Bat);
            app.BATTERYStandalonePVsystemsLabel.Text = 'BATTERY (Stand-alone PV systems)';
            app.HELPSwitchLabel_5 = uilabel(app.Bat);
            app.HELPSwitchLabel_5.Text = 'HELP';
            app.HELPBattery = uiswitch(app.Bat, 'toggle');
            app.HELPBattery.ValueChangedFcn = createCallbackFcn(app, @HELPBatteryValueChanged, true);
            app.Load = uitab(app.TabGroup);
            app.Load.Title = 'Load';
            app.LdmGraphic = uiaxes(app.Load);
            title(app.LdmGraphic, '')
            xlabel(app.LdmGraphic, 'Month')
            ylabel(app.LdmGraphic, 'Ldm [kWh/day]')
            app.LdmGraphic.XTick = [1 4 7 10];
            app.LdmGraphic.XTickLabel = {'January'; 'April'; 'July'; 'October'};
            app.LdmGraphic.YTick = [0 50 100];
            app.LdmGraphic.YTickLabel = {'0'; '50'; '100'};
            app.LdmI = uitable(app.Load);
            app.LdmI.ColumnName = {'Ldm'};
            app.LdmI.RowName = {'January'; 'February'; 'March'; 'April'; 'May'; 'June'; 'July'; 'August'; 'September'; 'October'; 'November'; 'December'};
            app.LdmI.ColumnEditable = true;
            app.LdmI.CellEditCallback = createCallbackFcn(app, @LdmICellEdit2, true);
            app.TotalEditFieldLabel = uilabel(app.Load);
            app.TotalEditFieldLabel.Text = 'Total';
            app.TotalLdmI = uieditfield(app.Load, 'numeric');
            app.TotalLdmI.ValueDisplayFormat = '%.1f';
            app.TotalLdmI.Editable = 'off';
            app.LdmLabel = uilabel(app.Load);
            app.LdmLabel.Visible = 'off';
            app.LdmLabel.Text = 'Monthly average of daily energy consumption, Ldm [kWh/day]';
            app.FhI = uitable(app.Load);
            app.FhI.ColumnName = {'F(h)'};
            app.FhI.RowName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'};
            app.FhI.ColumnEditable = true;
            app.FhI.CellEditCallback = createCallbackFcn(app, @FhICellEdit, true);
            app.FhGraphic = uiaxes(app.Load);
            title(app.FhGraphic, '')
            xlabel(app.FhGraphic, 'Hour')
            ylabel(app.FhGraphic, 'Hourly fraction, F(h)')
            app.FhGraphic.XTick = [0 4 8 12 16 20 24];
            app.FhGraphic.XTickLabel = {'0'; '4'; '8'; '12'; '16'; '20'; '24'};
            app.FhGraphic.YTick = [0 0.02 0.04 0.06 0.08 0.1 0.12 0.14 0.16];
            app.FhGraphic.YTickLabel = {'0'; '0.02'; '0.04'; '0.06'; '0.08'; '0.1'; '0.12'; '0.14'; '0.16'};
            app.FhLabel1 = uilabel(app.Load);
            app.FhLabel1.Visible = 'off';
            app.FhLabel1.Text = 'F(h) indicates the fraction of daily energy consumed';
            app.FhLabel2 = uilabel(app.Load);
            app.FhLabel2.Visible = 'off';
            app.FhLabel2.Text = ' during the hour h. Constant load profile: F(h)=1/24)';
            app.FhLabel3 = uilabel(app.Load);
            app.FhLabel3.Text = 'The sum of hourly fractions';
            app.FhLabel4 = uilabel(app.Load);
            app.FhLabel4.Text = ' must be 1:';
            app.TotalEditFieldLabel_2 = uilabel(app.Load);
            app.TotalEditFieldLabel_2.Text = 'Total';
            app.TotalFh = uieditfield(app.Load, 'numeric');
            app.TotalFh.ValueDisplayFormat = '%.4f';
            app.TotalFh.Editable = 'off';
            app.TotalFh.Value = 1;
            app.HELPSwitchLabel_6 = uilabel(app.Load);
            app.HELPSwitchLabel_6.Text = 'HELP';
            app.HELPLoad = uiswitch(app.Load, 'slider');
            app.HELPLoad.ValueChangedFcn = createCallbackFcn(app, @HELPLoadValueChanged, true);
            app.LOADPROFILESLabel = uilabel(app.Load);
            app.LOADPROFILESLabel.Text = 'LOAD PROFILES';
            app.GenSet = uitab(app.TabGroup);
            app.GenSet.Title = 'GenSet';
            app.PGENnomkWLabel = uilabel(app.GenSet);
            app.PGENnomkWLabel.Text = 'PGENnom (kW)';
            app.PGENnomI = uieditfield(app.GenSet, 'numeric');
            app.PGENnomI.Limits = [0 Inf];
            app.PGENnomI.ValueChangedFcn = createCallbackFcn(app, @PGENnomIValueChanged, true);
            app.SOCstartEditFieldLabel = uilabel(app.GenSet);
            app.SOCstartEditFieldLabel.Text = 'SOCstart';
            app.SOCstartI = uieditfield(app.GenSet, 'numeric');
            app.SOCstartI.ValueChangedFcn = createCallbackFcn(app, @SOCstartIValueChanged, true);
            app.SOCstopEditFieldLabel = uilabel(app.GenSet);
            app.SOCstopEditFieldLabel.Text = 'SOCstop';
            app.SOCstopI = uieditfield(app.GenSet, 'numeric');
            app.SOCstopI.ValueChangedFcn = createCallbackFcn(app, @SOCstopIValueChanged, true);
            app.b0iEditFieldLabel = uilabel(app.GenSet);
            app.b0iEditFieldLabel.Text = 'b0i';
            app.b0iI = uieditfield(app.GenSet, 'numeric');
            app.b0iI.ValueDisplayFormat = '%.4f';
            app.b0iI.ValueChangedFcn = createCallbackFcn(app, @b0iIValueChanged, true);
            app.b1iEditFieldLabel = uilabel(app.GenSet);
            app.b1iEditFieldLabel.Text = 'b1i';
            app.b1iI = uieditfield(app.GenSet, 'numeric');
            app.b1iI.ValueDisplayFormat = '%.4f';
            app.b1iI.ValueChangedFcn = createCallbackFcn(app, @b1iIValueChanged, true);
            app.b0sEditFieldLabel = uilabel(app.GenSet);
            app.b0sEditFieldLabel.Text = 'b0s';
            app.b0sI = uieditfield(app.GenSet, 'numeric');
            app.b0sI.ValueDisplayFormat = '%.4f';
            app.b0sI.ValueChangedFcn = createCallbackFcn(app, @b0sIValueChanged, true);
            app.b1sEditFieldLabel = uilabel(app.GenSet);
            app.b1sEditFieldLabel.Text = 'b1s';
            app.b1sI = uieditfield(app.GenSet, 'numeric');
            app.b1sI.ValueDisplayFormat = '%.4f';
            app.b1sI.ValueChangedFcn = createCallbackFcn(app, @b1sIValueChanged, true);
            app.b0iUnitsLabel = uilabel(app.GenSet);
            app.b0iUnitsLabel.Text = '(l/h)';
            app.b1iUnitsLabel = uilabel(app.GenSet);
            app.b1iUnitsLabel.Text = '(l/h)/kWnom';
            app.b0sUnitsLabel = uilabel(app.GenSet);
            app.b0sUnitsLabel.Text = '(l/h)/kW';
            app.b1sUnitsLabel = uilabel(app.GenSet);
            app.b1sUnitsLabel.Text = '(l/h)/(kWnom·kW)';
            app.PGENnomLabel = uilabel(app.GenSet);
            app.PGENnomLabel.Visible = 'off';
            app.PGENnomLabel.Text = 'Nominal power of the genset';
            app.SOCstartLabel = uilabel(app.GenSet);
            app.SOCstartLabel.Visible = 'off';
            app.SOCstartLabel.Text = 'State of charge for connecting the genset';
            app.SOCstopLabel = uilabel(app.GenSet);
            app.SOCstopLabel.Visible = 'off';
            app.SOCstopLabel.Text = 'State of charge for disconnecting the genset';
            app.FuelCons = uitable(app.GenSet);
            app.FuelCons.ColumnName = {'PGEN [kW]'; 'F [l/h]'};
            app.FuelCons.RowName = {};
            app.FuelConsGraph = uiaxes(app.GenSet);
            title(app.FuelConsGraph, 'Fuel consumption curve')
            xlabel(app.FuelConsGraph, 'Genset power, kW')
            ylabel(app.FuelConsGraph, 'Fuel consumption, l/h')
            app.FuelConsGraph.XTick = [0 5 10 15];
            app.FuelConsGraph.XTickLabel = {'0'; '5'; '10'; '15'};
            app.FuelConsGraph.YTick = [0 1 2 3 4 5 6 7];
            app.FuelConsGraph.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'};
            app.b0iLabel = uilabel(app.GenSet);
            app.b0iLabel.Visible = 'off';
            app.b0iLabel.Text = 'b0i: Intercept coefficient 0';
            app.b1iLabel = uilabel(app.GenSet);
            app.b1iLabel.Visible = 'off';
            app.b1iLabel.Text = 'b1i: Intercept coefficient 1';
            app.b0sLabel = uilabel(app.GenSet);
            app.b0sLabel.Visible = 'off';
            app.b0sLabel.Text = 'b0s: Slope coefficient 0';
            app.b1sLabel = uilabel(app.GenSet);
            app.b1sLabel.Visible = 'off';
            app.b1sLabel.Text = 'b1s: Slope coefficient 1';
            app.HELPSwitchLabel = uilabel(app.GenSet);
            app.HELPSwitchLabel.Text = 'HELP';
            app.HELPGenSet = uiswitch(app.GenSet, 'slider');
            app.HELPGenSet.ValueChangedFcn = createCallbackFcn(app, @HELPGenSetValueChanged, true);
            app.GENERATORSET = uilabel(app.GenSet);
            app.GENERATORSET.Text = 'GENERATOR SET';
            app.Wind = uitab(app.TabGroup);
            app.Wind.Title = 'Wind';
            app.WIND = uilabel(app.Wind);
            app.WIND.Text = 'WIND TURBINE';
            app.PWnomkWEditFieldLabel = uilabel(app.Wind);
            app.PWnomkWEditFieldLabel.Text = 'PWnom (kW)';
            app.PWnomI = uieditfield(app.Wind, 'numeric');
            app.PWnomI.ValueChangedFcn = createCallbackFcn(app, @PWnomIValueChanged, true);
            app.VnommsEditFieldLabel = uilabel(app.Wind);
            app.VnommsEditFieldLabel.Text = 'Vnom (m/s)';
            app.VnomI = uieditfield(app.Wind, 'numeric');
            app.VnomI.ValueChangedFcn = createCallbackFcn(app, @VnomIValueChanged, true);
            app.VcimsEditFieldLabel = uilabel(app.Wind);
            app.VcimsEditFieldLabel.Text = 'Vci (m/s)';
            app.VciI = uieditfield(app.Wind, 'numeric');
            app.VciI.ValueChangedFcn = createCallbackFcn(app, @VciIValueChanged, true);
            app.VcomsEditFieldLabel = uilabel(app.Wind);
            app.VcomsEditFieldLabel.Text = 'Vco (m/s)';
            app.VcoI = uieditfield(app.Wind, 'numeric');
            app.VcoI.ValueChangedFcn = createCallbackFcn(app, @VcoIValueChanged, true);
            app.CpeqkWms3EditFieldLabel = uilabel(app.Wind);
            app.CpeqkWms3EditFieldLabel.Text = 'Cpeq (kW/(m/s)3)';
            app.CpeqI = uieditfield(app.Wind, 'numeric');
            app.CpeqI.Editable = 'off';
            app.PWnomLabel = uilabel(app.Wind);
            app.PWnomLabel.Visible = 'off';
            app.PWnomLabel.Text = 'Rated electrical power';
            app.VnomLabel = uilabel(app.Wind);
            app.VnomLabel.Visible = 'off';
            app.VnomLabel.Text = 'Rated wind speed';
            app.VciLabel = uilabel(app.Wind);
            app.VciLabel.Visible = 'off';
            app.VciLabel.Text = 'Cut-in wind speed';
            app.VcoLabel = uilabel(app.Wind);
            app.VcoLabel.Visible = 'off';
            app.VcoLabel.Text = 'Cut-out wind speed';
            app.CpeqLabel = uilabel(app.Wind);
            app.CpeqLabel.Visible = 'off';
            app.CpeqLabel.Text = 'Equivalent power coefficient (Cpeq=0,5·r·A·Cp)r';
            app.HELPSwitchLabel_7 = uilabel(app.Wind);
            app.HELPSwitchLabel_7.Text = 'HELP';
            app.HELPWind = uiswitch(app.Wind, 'toggle');
            app.HELPWind.ValueChangedFcn = createCallbackFcn(app, @HELPWindValueChanged, true);
            app.PowerCurve = uitable(app.Wind);
            app.PowerCurve.ColumnName = {'Wind speed (m/s)'; 'Power (kW)'};
            app.PowerCurve.RowName = {};
            app.PowerCurveGraph = uiaxes(app.Wind);
            title(app.PowerCurveGraph, 'Power curve')
            xlabel(app.PowerCurveGraph, 'Wind speed (m/s)')
            ylabel(app.PowerCurveGraph, 'Power (kW)')
            app.PowerCurveGraph.XTick = [0 5 10 15 20];
            app.PowerCurveGraph.XTickLabel = {'0'; '5'; '10'; '15'; '20'};
            app.PowerCurveGraph.YTick = [0 5 10 15 20 25];
            app.PowerCurveGraph.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'};
            app.Pumping1 = uitab(app.TabGroup);
            app.Pumping1.Title = 'Pumping (1)';
            app.PUMPLabel = uilabel(app.Pumping1);
            app.PUMPLabel.Text = 'PUMP';
            app.Qratedm3hEditFieldLabel = uilabel(app.Pumping1);
            app.Qratedm3hEditFieldLabel.Text = 'Qrated (m3/h)';
            app.QratedI = uieditfield(app.Pumping1, 'numeric');
            app.QratedI.ValueChangedFcn = createCallbackFcn(app, @QratedIValueChanged, true);
            app.HratedmEditFieldLabel = uilabel(app.Pumping1);
            app.HratedmEditFieldLabel.Text = 'Hrated (m)';
            app.HratedI = uieditfield(app.Pumping1, 'numeric');
            app.HratedI.ValueChangedFcn = createCallbackFcn(app, @HratedIValueChanged, true);
            app.LiquidDensitykgm3EditFieldLabel = uilabel(app.Pumping1);
            app.LiquidDensitykgm3EditFieldLabel.Text = 'Liquid Density (kg/m3)';
            app.LiquidDensityI = uieditfield(app.Pumping1, 'numeric');
            app.LiquidDensityI.ValueChangedFcn = createCallbackFcn(app, @LiquidDensityIValueChanged, true);
            app.QratedLabel = uilabel(app.Pumping1);
            app.QratedLabel.Visible = 'off';
            app.QratedLabel.Text = 'Rated flow';
            app.HratedLabel = uilabel(app.Pumping1);
            app.HratedLabel.Visible = 'off';
            app.HratedLabel.Text = 'Rated head';
            app.densityLabel = uilabel(app.Pumping1);
            app.densityLabel.Visible = 'off';
            app.densityLabel.Text = 'Liquid density';
            app.WELLBOREHOLELabel = uilabel(app.Pumping1);
            app.WELLBOREHOLELabel.Text = 'WELL / BOREHOLE';
            app.HsmLabel = uilabel(app.Pumping1);
            app.HsmLabel.Text = 'Hs (m)';
            app.HsI = uieditfield(app.Pumping1, 'numeric');
            app.HsI.ValueChangedFcn = createCallbackFcn(app, @HsIValueChanged, true);
            app.Qtestm3hLabel = uilabel(app.Pumping1);
            app.Qtestm3hLabel.Text = 'Qtest (m3/h)';
            app.QtestI = uieditfield(app.Pumping1, 'numeric');
            app.QtestI.ValueChangedFcn = createCallbackFcn(app, @QtestIValueChanged, true);
            app.HdrmLabel = uilabel(app.Pumping1);
            app.HdrmLabel.Text = 'Hdr (m)';
            app.HdrI = uieditfield(app.Pumping1, 'numeric');
            app.HdrI.ValueChangedFcn = createCallbackFcn(app, @HdrIValueChanged, true);
            app.kw1mm3hLabel = uilabel(app.Pumping1);
            app.kw1mm3hLabel.Text = 'kw1 (m/(m3/h))';
            app.kw1I = uieditfield(app.Pumping1, 'numeric');
            app.kw1I.ValueChangedFcn = createCallbackFcn(app, @kw1IValueChanged, true);
            app.kw2mm3h2Label = uilabel(app.Pumping1);
            app.kw2mm3h2Label.Text = 'kw2 (m/(m3/h)2)';
            app.kw2I = uieditfield(app.Pumping1, 'numeric');
            app.kw2I.ValueChangedFcn = createCallbackFcn(app, @kw2IValueChanged, true);
            app.HsLabel = uilabel(app.Pumping1);
            app.HsLabel.Visible = 'off';
            app.HsLabel.Text = 'Static head';
            app.QtestLabel = uilabel(app.Pumping1);
            app.QtestLabel.Visible = 'off';
            app.QtestLabel.Text = 'Constant test flow';
            app.HdrLabel = uilabel(app.Pumping1);
            app.HdrLabel.Visible = 'off';
            app.HdrLabel.Text = 'Drawdown at constant test flow';
            app.kw1Label1 = uilabel(app.Pumping1);
            app.kw1Label1.Visible = 'off';
            app.kw1Label1.Text = 'Aquifer loss (this parameter is calculated as Hdr/Qtest, but other';
            app.kw1Label2 = uilabel(app.Pumping1);
            app.kw1Label2.Visible = 'off';
            app.kw1Label2.Text = 'value can be chosen if a stepdrawdown test is available).';
            app.kw2Label = uilabel(app.Pumping1);
            app.kw2Label.Visible = 'off';
            app.kw2Label.Text = 'Well loss (set to zero if only constant test flow is available).';
            app.WATERTANKLabel = uilabel(app.Pumping1);
            app.WATERTANKLabel.Text = 'WATER TANK';
            app.WTCm3Label = uilabel(app.Pumping1);
            app.WTCm3Label.Text = 'WTC (m3)';
            app.WTCI = uieditfield(app.Pumping1, 'numeric');
            app.WTCI.ValueChangedFcn = createCallbackFcn(app, @WTCIValueChanged, true);
            app.HrmLabel = uilabel(app.Pumping1);
            app.HrmLabel.Text = 'Hr (m)';
            app.HrI = uieditfield(app.Pumping1, 'numeric');
            app.HrI.ValueChangedFcn = createCallbackFcn(app, @HrIValueChanged, true);
            app.WTCLabel = uilabel(app.Pumping1);
            app.WTCLabel.Visible = 'off';
            app.WTCLabel.Text = 'Water tank capacity (in this version the water ';
            app.WTCLabel2 = uilabel(app.Pumping1);
            app.WTCLabel2.Visible = 'off';
            app.WTCLabel2.Text = 'storage is considered unlimited)';
            app.HrLabel = uilabel(app.Pumping1);
            app.HrLabel.Visible = 'off';
            app.HrLabel.Text = 'Discharge level';
            app.PUMPING1 = uilabel(app.Pumping1);
            app.PUMPING1.Text = 'WATER PUMPING (1/4)';
            app.HELPSwitchLabel_13 = uilabel(app.Pumping1);
            app.HELPSwitchLabel_13.Text = 'HELP';
            app.HELPPump1 = uiswitch(app.Pumping1, 'toggle');
            app.HELPPump1.ValueChangedFcn = createCallbackFcn(app, @HELPPump1ValueChanged, true);
            app.Pumping2 = uitab(app.TabGroup);
            app.Pumping2.Title = 'Pumping (2)';
            app.PUMPING2 = uilabel(app.Pumping2);
            app.PUMPING2.Text = 'WATER PUMPING (2/4)';
            app.ks0mLabel = uilabel(app.Pumping2);
            app.ks0mLabel.Text = 'ks0 (m)';
            app.ks0I = uieditfield(app.Pumping2, 'numeric');
            app.ks0I.Editable = 'off';
            app.ks1mm3hLabel = uilabel(app.Pumping2);
            app.ks1mm3hLabel.Text = 'ks1 (m/(m3/h))';
            app.ks1I = uieditfield(app.Pumping2, 'numeric');
            app.ks1I.ValueChangedFcn = createCallbackFcn(app, @ks1IValueChanged, true);
            app.HfmLabel = uilabel(app.Pumping2);
            app.HfmLabel.Text = 'Hf (m)';
            app.HfI = uieditfield(app.Pumping2, 'numeric');
            app.HfI.ValueChangedFcn = createCallbackFcn(app, @HfIValueChanged, true);
            app.ks0Label = uilabel(app.Pumping2);
            app.ks0Label.Visible = 'off';
            app.ks0Label.Text = 'Total static head (Hs+Hr)';
            app.ks1Label = uilabel(app.Pumping2);
            app.ks1Label.Visible = 'off';
            app.ks1Label.Text = 'Specific drawdown (aquifer loss)';
            app.HfLabel = uilabel(app.Pumping2);
            app.HfLabel.Visible = 'off';
            app.HfLabel.Text = 'Friction losses at rated flow Qrated';
            app.SYSTEMCURVELabel = uilabel(app.Pumping2);
            app.SYSTEMCURVELabel.Text = 'SYSTEM CURVE';
            app.systemCurveTable = uitable(app.Pumping2);
            app.systemCurveTable.ColumnName = {'Flow (Q) [m3/h]'; 'Head (H) [m]'};
            app.systemCurveTable.RowName = {};
            app.systemCurveGraph = uiaxes(app.Pumping2);
            title(app.systemCurveGraph, 'System curve')
            xlabel(app.systemCurveGraph, 'Q [m3/h]')
            ylabel(app.systemCurveGraph, 'H [m]')
            app.systemCurveGraph.XTick = [0 2 4 6 8];
            app.systemCurveGraph.XTickLabel = {'0'; '2'; '4'; '6'; '8'};
            app.HELPSwitchLabel_9 = uilabel(app.Pumping2);
            app.HELPSwitchLabel_9.Text = 'HELP';
            app.HELPPump2 = uiswitch(app.Pumping2, 'toggle');
            app.HELPPump2.ValueChangedFcn = createCallbackFcn(app, @HELPPump2ValueChanged, true);
            app.ks2mm3h2Label = uilabel(app.Pumping2);
            app.ks2mm3h2Label.Text = 'ks2 (m/(m3/h)2)';
            app.ks2I = uieditfield(app.Pumping2, 'numeric');
            app.ks2I.Editable = 'off';
            app.ks2Label = uilabel(app.Pumping2);
            app.ks2Label.Visible = 'off';
            app.ks2Label.Text = 'Friction plus well losses';
            app.Pumping3 = uitab(app.TabGroup);
            app.Pumping3.Title = 'Pumping (3)';
            app.pumpCurveTable = uitable(app.Pumping3);
            app.pumpCurveTable.ColumnName = {'Q [m3/h]'; 'H [m]'};
            app.pumpCurveTable.RowName = {};
            app.pumpCurveTable.ColumnEditable = true;
            app.pumpCurveTable.CellEditCallback = createCallbackFcn(app, @pumpCurveTableCellEdit, true);
            app.pumpCurveGraph = uiaxes(app.Pumping3);
            title(app.pumpCurveGraph, 'Pump curve')
            xlabel(app.pumpCurveGraph, 'Q [m3/h]')
            ylabel(app.pumpCurveGraph, 'H [m]')
            app.pumpCurveGraph.XTick = [0 2 4 6 8];
            app.pumpCurveGraph.XTickLabel = {'0'; '2'; '4'; '6'; '8'};
            app.pumpCurveGraph.YTick = [0 5 10 15 20 25 30];
            app.pumpCurveGraph.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'; '30'};
            app.powerCurvesTable = uitable(app.Pumping3);
            app.powerCurvesTable.ColumnName = {'Q [m3/h]'; 'P1 [kW]'; 'P2 [kW]'};
            app.powerCurvesTable.RowName = {};
            app.powerCurvesTable.ColumnEditable = true;
            app.powerCurvesTable.CellEditCallback = createCallbackFcn(app, @powerCurvesTableCellEdit, true);
            app.powerCurvesGraph = uiaxes(app.Pumping3);
            title(app.powerCurvesGraph, {'P1 (magenta) vs P2 (blue)'; ''})
            xlabel(app.powerCurvesGraph, 'Q [m3/h]')
            ylabel(app.powerCurvesGraph, 'Power Curves [kW]')
            app.powerCurvesGraph.XTick = [0 2 4 6 8];
            app.powerCurvesGraph.XTickLabel = {'0'; '2'; '4'; '6'; '8'};
            app.powerCurvesGraph.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6];
            app.powerCurvesGraph.YTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'};
            app.P1Label1 = uilabel(app.Pumping3);
            app.P1Label1.Visible = 'off';
            app.P1Label1.Text = 'P1: nominal curve of the power';
            app.P1Label2 = uilabel(app.Pumping3);
            app.P1Label2.Visible = 'off';
            app.P1Label2.Text = ' at the motor input, kW';
            app.P2Label1 = uilabel(app.Pumping3);
            app.P2Label1.Visible = 'off';
            app.P2Label1.Text = 'P2: nominal curve of shaft';
            app.P2Label2 = uilabel(app.Pumping3);
            app.P2Label2.Visible = 'off';
            app.P2Label2.Text = 'power, kW';
            app.PUMPING3 = uilabel(app.Pumping3);
            app.PUMPING3.Text = 'WATER PUMPING (3/4)';
            app.PUMPCURVELabel = uilabel(app.Pumping3);
            app.PUMPCURVELabel.Text = 'PUMP CURVE';
            app.POWERCURVESLabel = uilabel(app.Pumping3);
            app.POWERCURVESLabel.Text = 'POWER CURVES';
            app.HELPSwitchLabel_10 = uilabel(app.Pumping3);
            app.HELPSwitchLabel_10.Text = 'HELP';
            app.HELPPump3 = uiswitch(app.Pumping3, 'toggle');
            app.HELPPump3.ValueChangedFcn = createCallbackFcn(app, @HELPPump3ValueChanged, true);
            app.Pumping4 = uitab(app.TabGroup);
            app.Pumping4.Title = 'Pumping (4)';
            app.PUMPING4 = uilabel(app.Pumping4);
            app.PUMPING4.Text = 'WATER PUMPING (4/4)';
            app.MOTORLabel = uilabel(app.Pumping4);
            app.MOTORLabel.Text = 'MOTOR';
            app.P2ratedkWEditFieldLabel = uilabel(app.Pumping4);
            app.P2ratedkWEditFieldLabel.Text = 'P2rated (kW)';
            app.P2ratedI = uieditfield(app.Pumping4, 'numeric');
            app.P2ratedI.ValueChangedFcn = createCallbackFcn(app, @P2ratedIValueChanged, true);
            app.RPMnomrpmLabel = uilabel(app.Pumping4);
            app.RPMnomrpmLabel.Text = 'RPMnom (rpm)';
            app.RPMnomI = uieditfield(app.Pumping4, 'numeric');
            app.RPMnomI.ValueChangedFcn = createCallbackFcn(app, @RPMnomIValueChanged, true);
            app.RPMcool0100Label = uilabel(app.Pumping4);
            app.RPMcool0100Label.Text = 'RPMcool (0-100%)';
            app.RPMcoolI = uieditfield(app.Pumping4, 'numeric');
            app.RPMcoolI.Limits = [0 100];
            app.RPMcoolI.ValueChangedFcn = createCallbackFcn(app, @RPMcoolIValueChanged, true);
            app.RPMmax100150Label = uilabel(app.Pumping4);
            app.RPMmax100150Label.Text = 'RPMmax (100-150%)';
            app.RPMmaxI = uieditfield(app.Pumping4, 'numeric');
            app.RPMmaxI.Limits = [100 150];
            app.RPMmaxI.ValueChangedFcn = createCallbackFcn(app, @RPMmaxIValueChanged, true);
            app.RPMmaxI.Value = 100;
            app.P2RatedLabel = uilabel(app.Pumping4);
            app.P2RatedLabel.Visible = 'off';
            app.P2RatedLabel.Text = 'Rated shaft power';
            app.RPMnomLabel = uilabel(app.Pumping4);
            app.RPMnomLabel.Visible = 'off';
            app.RPMnomLabel.Text = 'Rated speed';
            app.RPMcoolLabel = uilabel(app.Pumping4);
            app.RPMcoolLabel.Visible = 'off';
            app.RPMcoolLabel.Text = 'Minimum speed, relative to the rated speed, for water cooling';
            app.RPMmaxLabel = uilabel(app.Pumping4);
            app.RPMmaxLabel.Visible = 'off';
            app.RPMmaxLabel.Text = 'Maximum speed, relative to the rated speed';
            app.PowerEfficTable = uitable(app.Pumping4);
            app.PowerEfficTable.ColumnName = {'P2 [kW]'; 'Efficiency [%]'};
            app.PowerEfficTable.RowName = {};
            app.PowerEfficTable.ColumnEditable = true;
            app.PowerEfficTable.CellEditCallback = createCallbackFcn(app, @PowerEfficTableCellEdit, true);
            app.PowerEfficGraph = uiaxes(app.Pumping4);
            title(app.PowerEfficGraph, 'Power efficiency')
            xlabel(app.PowerEfficGraph, 'Shaft power, P2 [kW]')
            ylabel(app.PowerEfficGraph, 'Efficiency [%]')
            app.PowerEfficGraph.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.PowerEfficGraph.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.PowerEfficGraph.YTick = [0 20 40 60 80 100];
            app.PowerEfficGraph.YTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.HELPSwitchLabel_11 = uilabel(app.Pumping4);
            app.HELPSwitchLabel_11.Text = 'HELP';
            app.HELPPump4 = uiswitch(app.Pumping4, 'toggle');
            app.HELPPump4.ValueChangedFcn = createCallbackFcn(app, @HELPPump4ValueChanged, true);
            app.Options = uitab(app.TabGroup);
            app.Options.Title = 'Options';
            app.PVApplicationLabel = uilabel(app.Options);
            app.PVApplicationLabel.Text = 'PV Application';
            app.ApplicationI = uilistbox(app.Options);
            app.ApplicationI.Items = {'Grid-Connected', 'Stand-alone PV system', 'Hybrid PV-diesel', 'Hybrid PV-wind-diesel', 'Water pumping'};
            app.ApplicationI.ValueChangedFcn = createCallbackFcn(app, @ApplicationIValueChanged, true);
            app.ApplicationI.Value = 'Grid-Connected';
            app.DiffuseskymodelDropDownLabel = uilabel(app.Options);
            app.DiffuseskymodelDropDownLabel.Text = 'Diffuse sky model';
            app.DiffuseModel = uidropdown(app.Options);
            app.DiffuseModel.Items = {'Isotropic', 'Anisotropic (Hay)', 'Anisotropic (Perez)'};
            app.DiffuseModel.ValueChangedFcn = createCallbackFcn(app, @DiffuseModelValueChanged, true);
            app.DiffuseModel.Value = 'Isotropic';
            app.DustLabel = uilabel(app.Options);
            app.DustLabel.Text = 'Degree of soiling on the PV generator under normal incidence';
            app.DustDegreeI = uibuttongroup(app.Options);
            app.DustDegreeI.SelectionChangedFcn = createCallbackFcn(app, @DustDegreeISelectionChanged, true);
            app.DustDegreeI.Title = 'Dust degree';
            app.Clean = uitogglebutton(app.DustDegreeI);
            app.Clean.Text = 'Clean (0%)';
            app.Clean.Value = true;
            app.Low = uitogglebutton(app.DustDegreeI);
            app.Low.Text = 'Low (2%)';
            app.Medium = uitogglebutton(app.DustDegreeI);
            app.Medium.Text = 'Medium (3%)';
            app.High = uitogglebutton(app.DustDegreeI);
            app.High.Text = 'High (8%)';
            app.GroundreflectanceSpinnerLabel = uilabel(app.Options);
            app.GroundreflectanceSpinnerLabel.Text = 'Ground reflectance';
            app.GroundReflectanceI = uispinner(app.Options);
            app.GroundReflectanceI.Step = 0.05;
            app.GroundReflectanceI.Limits = [0 0.5];
            app.GroundReflectanceI.ValueChangedFcn = createCallbackFcn(app, @GroundReflectanceIValueChanged, true);
            app.DiffuseFractionI = uibuttongroup(app.Options);
            app.DiffuseFractionI.SelectionChangedFcn = createCallbackFcn(app, @DiffuseFractionISelectionChanged, true);
            app.DiffuseFractionI.Title = 'Monthly diffuse correlation';
            app.Page = uiradiobutton(app.DiffuseFractionI);
            app.Page.Text = 'Page';
            app.Page.Value = true;
            app.CollaresPereira = uiradiobutton(app.DiffuseFractionI);
            app.CollaresPereira.Text = 'Collares-Pereira';
            app.Erbs = uiradiobutton(app.DiffuseFractionI);
            app.Erbs.Text = 'Erbs';
            app.Macagnan = uiradiobutton(app.DiffuseFractionI);
            app.Macagnan.Text = 'Macagnan';
            app.SimulationstepEditFieldLabel = uilabel(app.Options);
            app.SimulationstepEditFieldLabel.Text = 'Simulation step';
            app.SimulationstepI = uieditfield(app.Options, 'numeric');
            app.SimulationstepI.Limits = [60 3600];
            app.SimulationstepI.RoundFractionalValues = 'on';
            app.SimulationstepI.ValueChangedFcn = createCallbackFcn(app, @SimulationstepIValueChanged, true);
            app.SimulationstepI.Value = 60;
            app.to3600secondsLabel = uilabel(app.Options);
            app.to3600secondsLabel.Text = '(60 to 3600 seconds)';
            app.SIMULATIONOPTIONSLabel = uilabel(app.Options);
            app.SIMULATIONOPTIONSLabel.Text = 'SIMULATION OPTIONS';
            app.calculate = uibutton(app.UIFigure, 'push');
            app.calculate.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculate.Text = 'CALCULATE';
            app.SaveData = uicheckbox(app.UIFigure);
            app.SaveData.ValueChangedFcn = createCallbackFcn(app, @SaveDataValueChanged, true);
            app.SaveData.Text = 'SAVE DATA';
            app.instructions = uilabel(app.UIFigure);
            app.instructions.Text = 'To save input values, select ''SAVE DATA'', insert the project name and press ''CALCULATE''';
            app.ProjectnameLabel = uilabel(app.UIFigure);
            app.ProjectnameLabel.Text = 'Project name';
            app.nameI = uieditfield(app.UIFigure, 'text');
            app.nameI.ValueChangedFcn = createCallbackFcn(app, @nameIValueChanged, true);
            app.loadProject = uibutton(app.UIFigure, 'push');
            app.loadProject.ButtonPushedFcn = createCallbackFcn(app, @loadProjectButtonPushed, true);
            app.loadProject.Text = 'LOAD PROJECT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appInterface1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end